classdef download_upload < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ICESUpAndDownloaderUIFigure     matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        UploadTab                       matlab.ui.container.Tab
        DownloadTab                     matlab.ui.container.Tab
        GeographicalLimitsPanel         matlab.ui.container.Panel
        NorthSpinnerLabel               matlab.ui.control.Label
        NorthSpinner                    matlab.ui.control.Spinner
        SouthSpinnerLabel               matlab.ui.control.Label
        SouthSpinner                    matlab.ui.control.Spinner
        EastSpinnerLabel                matlab.ui.control.Label
        EastSpinner                     matlab.ui.control.Spinner
        WestSpinnerLabel                matlab.ui.control.Label
        WestSpinner                     matlab.ui.control.Spinner
        TemporalLimitsPanel             matlab.ui.container.Panel
        StartDateDatePickerLabel        matlab.ui.control.Label
        StartDateDatePicker             matlab.ui.control.DatePicker
        EndDateDatePickerLabel          matlab.ui.control.Label
        EndDateDatePicker               matlab.ui.control.DatePicker
        GetavailabledatafromICESButton  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        DownloadSelectionButton         matlab.ui.control.Button
        uimap                           matlab.ui.control.UIAxes
        BSH2023Label                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        libpath = addpath('./libs')
        startdate = datetime(2013,1,1)% user defined start date of period for which ICES database will be searched
        enddate = datetime(2023,9,13)% user defined end date of period for which ICES database will be searched
        northlim = 69 % user defined geographical limits
        southlim = 50    
        eastlim = 29
        westlim = -7
        avdata % available data at ICES database for chosen period and area
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.SelectionType = 'row';
            app.UITable.Multiselect = 'on';
        end

        % Value changed function: StartDateDatePicker
        function StartDateDatePickerValueChanged(app, event)
            app.startdate = app.StartDateDatePicker.Value;
        end

        % Value changed function: EndDateDatePicker
        function EndDateDatePickerValueChanged(app, event)
            app.enddate = app.EndDateDatePicker.Value;
        end

        % Value changed function: NorthSpinner
        function NorthSpinnerValueChanged(app, event)
            app.northlim = app.NorthSpinner.Value;
        end

        % Value changed function: SouthSpinner
        function SouthSpinnerValueChanged(app, event)
            app.southlim = app.SouthSpinner.Value;
        end

        % Value changed function: EastSpinner
        function EastSpinnerValueChanged(app, event)
            app.eastlim = app.EastSpinner.Value;
        end

        % Value changed function: WestSpinner
        function WestSpinnerValueChanged(app, event)
            app.westlim = app.WestSpinner.Value;
        end

        % Button pushed function: GetavailabledatafromICESButton
        function GetavailabledatafromICESButtonPushed(app, event)
            avdata = scanICES(app.uimap,app.westlim,app.eastlim,app.southlim,app.northlim,app.startdate,app.enddate);
            for ii = 1:length(avdata)
                station{ii} = avdata{ii}.station_name;
                nofiles(ii) = length(avdata{ii}.filename);
                nodays(ii) = avdata{ii}.nodays;
                key{ii} = avdata{ii}.key;
            end

            avdatatable = table(station',key',nofiles',nodays');
            app.UITable.Data = avdatatable;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices; %% indices of selected stations to be downloaded
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ICESUpAndDownloaderUIFigure = uifigure('Visible', 'off');
            app.ICESUpAndDownloaderUIFigure.Name = 'ICES UpAndDownloader';
            app.ICESUpAndDownloaderUIFigure.Icon = 'ices.gif';
            app.Menu = uimenu(app.ICESUpAndDownloaderUIFigure);
            app.Menu.Text = 'Menu';
            app.HelpMenu = uimenu(app.ICESUpAndDownloaderUIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.ICESUpAndDownloaderUIFigure);
            app.UploadTab = uitab(app.TabGroup);
            app.UploadTab.Title = 'Upload';
            app.DownloadTab = uitab(app.TabGroup);
            app.DownloadTab.Title = 'Download';
            app.GeographicalLimitsPanel = uipanel(app.DownloadTab);
            app.GeographicalLimitsPanel.Title = 'Geographical Limits';
            app.NorthSpinnerLabel = uilabel(app.GeographicalLimitsPanel);
            app.NorthSpinnerLabel.Text = 'North';
            app.NorthSpinner = uispinner(app.GeographicalLimitsPanel);
            app.NorthSpinner.Limits = [-90 90];
            app.NorthSpinner.ValueChangedFcn = createCallbackFcn(app, @NorthSpinnerValueChanged, true);
            app.NorthSpinner.Value = 69;
            app.SouthSpinnerLabel = uilabel(app.GeographicalLimitsPanel);
            app.SouthSpinnerLabel.Text = 'South';
            app.SouthSpinner = uispinner(app.GeographicalLimitsPanel);
            app.SouthSpinner.Limits = [-90 90];
            app.SouthSpinner.ValueChangedFcn = createCallbackFcn(app, @SouthSpinnerValueChanged, true);
            app.SouthSpinner.Value = 50;
            app.EastSpinnerLabel = uilabel(app.GeographicalLimitsPanel);
            app.EastSpinnerLabel.Text = 'East';
            app.EastSpinner = uispinner(app.GeographicalLimitsPanel);
            app.EastSpinner.Limits = [-180 180];
            app.EastSpinner.ValueChangedFcn = createCallbackFcn(app, @EastSpinnerValueChanged, true);
            app.EastSpinner.Value = 29;
            app.WestSpinnerLabel = uilabel(app.GeographicalLimitsPanel);
            app.WestSpinnerLabel.Text = 'West';
            app.WestSpinner = uispinner(app.GeographicalLimitsPanel);
            app.WestSpinner.Limits = [-180 180];
            app.WestSpinner.ValueChangedFcn = createCallbackFcn(app, @WestSpinnerValueChanged, true);
            app.WestSpinner.Value = -7;
            app.TemporalLimitsPanel = uipanel(app.DownloadTab);
            app.TemporalLimitsPanel.Title = 'Temporal Limits';
            app.StartDateDatePickerLabel = uilabel(app.TemporalLimitsPanel);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.TemporalLimitsPanel);
            app.StartDateDatePicker.Limits = [datetime([2013 1 1]) datetime([2060 12 31])];
            app.StartDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePickerValueChanged, true);
            app.StartDateDatePicker.Value = datetime([2013 1 1]);
            app.EndDateDatePickerLabel = uilabel(app.TemporalLimitsPanel);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.TemporalLimitsPanel);
            app.EndDateDatePicker.Limits = [datetime([2013 1 1]) datetime([2060 12 31])];
            app.EndDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EndDateDatePickerValueChanged, true);
            app.EndDateDatePicker.Value = datetime([2023 9 13]);
            app.GetavailabledatafromICESButton = uibutton(app.DownloadTab, 'push');
            app.GetavailabledatafromICESButton.ButtonPushedFcn = createCallbackFcn(app, @GetavailabledatafromICESButtonPushed, true);
            app.GetavailabledatafromICESButton.Text = 'Get available data from ICES';
            app.UITable = uitable(app.DownloadTab);
            app.UITable.ColumnName = {'Station'; 'ID'; 'no. of files'; 'no. of days'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false true false];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.DownloadSelectionButton = uibutton(app.DownloadTab, 'push');
            app.DownloadSelectionButton.Text = 'Download Selection';
            app.uimap = uiaxes(app.DownloadTab);
            app.uimap.XTick = [];
            app.uimap.XTickLabel = '';
            app.uimap.YTick = [];
            app.uimap.YTickLabel = '';
            app.BSH2023Label = uilabel(app.ICESUpAndDownloaderUIFigure);
            app.BSH2023Label.Text = 'BSH, 2023';
            % Show the figure after all components are created
            app.ICESUpAndDownloaderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = download_upload

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ICESUpAndDownloaderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ICESUpAndDownloaderUIFigure)
        end
    end
end