classdef format3000 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        LoadBSHh5fileMenu               matlab.ui.container.Menu
        LoadPAMGuidecsvfileMenu         matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        SeeICEScontinuousnoisedataformatMenu  matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        FileInformationTab              matlab.ui.container.Tab
        EmailEditFieldLabel             matlab.ui.control.Label
        EmailEditField                  matlab.ui.control.EditField
        CreationDateDatePickerLabel     matlab.ui.control.Label
        CreationDateDatePicker          matlab.ui.control.DatePicker
        StartDateDatePickerLabel        matlab.ui.control.Label
        StartDateDatePicker             matlab.ui.control.DatePicker
        EndDateDatePickerLabel          matlab.ui.control.Label
        EndDateDatePicker               matlab.ui.control.DatePicker
        ContactEditFieldLabel           matlab.ui.control.Label
        ContactEditField                matlab.ui.control.EditField
        CountryCodeDropDownLabel        matlab.ui.control.Label
        CountryCodeDropDown             matlab.ui.control.DropDown
        TimeEditFieldLabel              matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.EditField
        TimeEditField_2Label            matlab.ui.control.Label
        TimeEditField_2                 matlab.ui.control.EditField
        TimeEditField_3Label            matlab.ui.control.Label
        TimeEditField_3                 matlab.ui.control.EditField
        InstitutionEditFieldLabel       matlab.ui.control.Label
        InstitutionEditField            matlab.ui.control.EditField
        Lamp_Email                      matlab.ui.control.Lamp
        Lamp_CreationDate               matlab.ui.control.Lamp
        Lamp_StartDate                  matlab.ui.control.Lamp
        Lamp_EndDate                    matlab.ui.control.Lamp
        Lamp_Institution                matlab.ui.control.Lamp
        Lamp_Contact                    matlab.ui.control.Lamp
        Lamp_CountryCode                matlab.ui.control.Lamp
        Lamp_StationCode                matlab.ui.control.Lamp
        SelectDropDownLabel             matlab.ui.control.Label
        SelectDropDown                  matlab.ui.control.DropDown
        StationCodeEditFieldLabel       matlab.ui.control.Label
        StationCodeEditField            matlab.ui.control.EditField
        SelectDropDown_2Label           matlab.ui.control.Label
        SelectDropDown_StationCode      matlab.ui.control.DropDown
        Image                           matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        Image_5                         matlab.ui.control.Image
        Image_6                         matlab.ui.control.Image
        Image_7                         matlab.ui.control.Image
        Image_8                         matlab.ui.control.Image
        MetadataTab                     matlab.ui.container.Tab
        CreateUUIDButton                matlab.ui.control.Button
        HydrophoneTypeDropDownLabel     matlab.ui.control.Label
        HydrophoneTypeDropDown          matlab.ui.control.DropDown
        HydrophoneSerialNumberEditFieldLabel  matlab.ui.control.Label
        HydrophoneSerialNumberEditField  matlab.ui.control.EditField
        RecorderTypeDropDownLabel       matlab.ui.control.Label
        RecorderTypeDropDown            matlab.ui.control.DropDown
        RecorderSerialNumberEditFieldLabel  matlab.ui.control.Label
        RecorderSerialNumberEditField   matlab.ui.control.EditField
        MeasurementHeightEditFieldLabel  matlab.ui.control.Label
        MeasurementHeightEditField      matlab.ui.control.EditField
        MeasurementPurposeDropDownLabel  matlab.ui.control.Label
        MeasurementPurposeDropDown      matlab.ui.control.DropDown
        MeasurementSetupDropDownLabel   matlab.ui.control.Label
        MeasurementSetupDropDown        matlab.ui.control.DropDown
        RigDesignDropDownLabel          matlab.ui.control.Label
        RigDesignDropDown               matlab.ui.control.DropDown
        FrequencyCountSpinnerLabel      matlab.ui.control.Label
        FrequencyCountSpinner           matlab.ui.control.Spinner
        FrequencyUnitDropDownLabel      matlab.ui.control.Label
        FrequencyUnitDropDown           matlab.ui.control.DropDown
        ChannelCountSpinnerLabel        matlab.ui.control.Label
        ChannelCountSpinner             matlab.ui.control.Spinner
        MeasurementUnitDropDownLabel    matlab.ui.control.Label
        MeasurementUnitDropDown         matlab.ui.control.DropDown
        AveragingTimeSpinnerLabel       matlab.ui.control.Label
        AveragingTimeSpinner            matlab.ui.control.Spinner
        ProcessingAlgorithmDropDownLabel  matlab.ui.control.Label
        ProcessingAlgorithmDropDown     matlab.ui.control.DropDown
        DataUUIDEditFieldLabel          matlab.ui.control.Label
        DataUUIDEditField               matlab.ui.control.EditField
        DatasetVersionSpinnerLabel      matlab.ui.control.Label
        DatasetVersionSpinner           matlab.ui.control.Spinner
        CalibrationProcedureDropDownLabel  matlab.ui.control.Label
        CalibrationProcedureDropDown    matlab.ui.control.DropDown
        Lamp_HydrophoneType             matlab.ui.control.Lamp
        Lamp_HydrophoneSerialNumber     matlab.ui.control.Lamp
        Lamp_RecorderType               matlab.ui.control.Lamp
        Lamp_RecorderSerialNumber       matlab.ui.control.Lamp
        Lamp_MeasurementHeight          matlab.ui.control.Lamp
        Lamp_MeasurementPurpose         matlab.ui.control.Lamp
        Lamp_MeasurementSetup           matlab.ui.control.Lamp
        Lamp_RigDesign                  matlab.ui.control.Lamp
        Lamp_FrequencyCount             matlab.ui.control.Lamp
        Lamp_FrequencyIndex             matlab.ui.control.Lamp
        FrequencyIndexPanel             matlab.ui.container.Panel
        individualFrequencyBandsCheckBox  matlab.ui.control.CheckBox
        consecutiveThirdOctaveBandsCheckBox  matlab.ui.control.CheckBox
        LowestTOBDropDownLabel          matlab.ui.control.Label
        LowestTOBDropDown               matlab.ui.control.DropDown
        HighestTOBDropDownLabel         matlab.ui.control.Label
        HighestTOBDropDown              matlab.ui.control.DropDown
        FrequencyIndexEditField         matlab.ui.control.EditField
        LoadFrequencyBandsfromCheckBox  matlab.ui.control.CheckBox
        fileButton                      matlab.ui.control.Button
        Lamp_FrequencyUnit              matlab.ui.control.Lamp
        Lamp_ChannelCount               matlab.ui.control.Lamp
        MeasurementTotalNoEditFieldLabel  matlab.ui.control.Label
        MeasurementTotalNoEditField     matlab.ui.control.NumericEditField
        Lamp_MeasurementTotalNo         matlab.ui.control.Lamp
        Lamp_MeasurementUnit            matlab.ui.control.Lamp
        Lamp_AveragingTime              matlab.ui.control.Lamp
        Lamp_ProcessingAlgorithm        matlab.ui.control.Lamp
        Lamp_DataUUID                   matlab.ui.control.Lamp
        Lamp_DatasetVersion             matlab.ui.control.Lamp
        Lamp_CalibrationProcedure       matlab.ui.control.Lamp
        Lamp_CalibrationDatetime        matlab.ui.control.Lamp
        CalibrationDatetimeDatePickerLabel  matlab.ui.control.Label
        CalibrationDatetimeDatePicker   matlab.ui.control.DatePicker
        TimeEditField_4Label            matlab.ui.control.Label
        TimeEditField_4                 matlab.ui.control.EditField
        Lamp_Comments                   matlab.ui.control.Lamp
        CommentsTextAreaLabel           matlab.ui.control.Label
        CommentsEditField               matlab.ui.control.TextArea
        Image_9                         matlab.ui.control.Image
        Image_10                        matlab.ui.control.Image
        Image_11                        matlab.ui.control.Image
        Image_12                        matlab.ui.control.Image
        Image_13                        matlab.ui.control.Image
        Image_14                        matlab.ui.control.Image
        Image_15                        matlab.ui.control.Image
        Image_16                        matlab.ui.control.Image
        Image_17                        matlab.ui.control.Image
        Image_18                        matlab.ui.control.Image
        Image_19                        matlab.ui.control.Image
        Image_20                        matlab.ui.control.Image
        Image_21                        matlab.ui.control.Image
        Image_22                        matlab.ui.control.Image
        Image_23                        matlab.ui.control.Image
        Image_24                        matlab.ui.control.Image
        Image_25                        matlab.ui.control.Image
        Image_27                        matlab.ui.control.Image
        Image_28                        matlab.ui.control.Image
        Image_29                        matlab.ui.control.Image
        Image_30                        matlab.ui.control.Image
        fromfileButton_2                matlab.ui.control.Button
        fromfileButton                  matlab.ui.control.Button
        DataTab                         matlab.ui.container.Tab
        SelectDatetimeVectorButton      matlab.ui.control.Button
        DateTimeLabel                   matlab.ui.control.Label
        Lamp_DateTime                   matlab.ui.control.Lamp
        LeqMeasurementsPanel            matlab.ui.container.Panel
        Image_31                        matlab.ui.control.Image
        Image_32                        matlab.ui.control.Image
        WritetoICESh5fileButton         matlab.ui.control.Button
        MovealldatatoMATLABworkspaceButton  matlab.ui.control.Button
        Viewh5filewithhdfviewButton     matlab.ui.control.Button
        GotoICEScontinuousnoiseregistryButton  matlab.ui.control.Button
        BSH2021Label                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        Email % Description
        CreationDate % Description
        StartDate
        EndDate
        Institution
        Contact
        CountryCode
        StationCode
        libpath = addpath('./libs')
        HydrophoneType % Description
        HydrophoneSerialNumber % Description
        RecorderType % Description
        RecorderSerialNumber % Description
        MeasurementHeight
        MeasurementPurpose % Description
        MeasurementSetup % Description
        RigDesign % Description
        FrequencyCount % Description
        fi_l % lower boundary of frequency index
        fi_u % upper boundary of frequency index
        fi % frequency index
        fu % Description
        ChannelCount % Description
        MeasurementTotalNo % Description
        MeasurementUnit % Description
        AveragingTime % Description
        ProcessingAlgorithm % Description
        UUID % Description
        DatasetVersion % Description
        CalibrationProcedure % Description
        CalibrationDate % Description
        calibrationtime
        comments % Description
        ActCH = '1' % Description
        datetimeindex % Description
        leq % Description
        creationtime
        starttime
        loadpath % Description
        endtime% Description
        New % Description
        saveh5name
    end


    methods (Access = private)
        
        function leqbuttondown(app, event,tt)
            %% get Channel
            p=max(strfind(event.Text,' '));
            channel = str2num(event.Text(p+1:end));
            %% get data
            if ~isempty(app.loadpath)
                lastdir = app.loadpath;
            else
                lastdir = fileparts(mfilename('fullpath'));
            end
            [file,path] = uigetfile({'*.h5;*.mat;*.csv','Data Files (*.h5,*.mat,*.csv)';'*.*','All Files (*.*)'}, ...
            'Select File',lastdir); 
            drawnow;
            figure(app.UIFigure)
            p = max(strfind(file,'.'));
            type = file(p+1:end);
            if isequal(type,'mat')
                F = load([path file]);
                F = struct2cell(F);
                F = cell2mat(F);
                app.leq{1,channel} = double(F);
                app.LeqMeasurementsPanel.Children(end-(channel*2-1)).Color = 'g';
            elseif isequal(type,'csv')
                F = importdata([path file]);
                app.leq{1,channel} = double(F);
                app.LeqMeasurementsPanel.Children(end-(channel*2-1)).Color = 'g';
            elseif isequal(type,'h5')
                I=h5info([path file]);
%                 IG = I.Groups;
%                 for ii =1:length(IG)
%                     for jj=1:length(IG(ii).Datasets)
%                         name{ii,jj}=IG(ii).Datasets(jj).Name;
%                     end
%                 end
%                 emptyCells = cellfun(@isempty,name);
%                 datasets=name(emptyCells==0);
                 I=h5info([path file]);
 
                 %% datasets, die in der ersten Ebene liegen
                 idx=1;
                 for ii =1:length(I.Datasets)
                        name{idx}=I.Datasets(ii).Name;
                        group{idx}=I.Name;
                        idx=idx+1;
                 end
                 %% check wie viele Gruppen gibt es
                 if length(I.Groups)>=1
                    for ii=1:length(I.Groups)
                        for jj=1:length(I.Groups(ii).Datasets)
                            name{idx}= I.Groups(ii).Datasets(jj).Name;
                            group{idx}= I.Groups(ii).Name;
                            idx=idx+1;
                        end
                        if length(I.Groups(ii).Groups)>=1
                            for kk=1:length(I.Groups(ii).Groups)
                                for ll=1:length(I.Groups(ii).Groups(kk).Datasets)
                                    name{idx}= I.Groups(ii).Groups(kk).Datasets(ll).Name;
                                    group{idx}= I.Groups(ii).Groups(kk).Name;
                                    idx=idx+1;
                                end
                            end
                        end
                    end
                 end
                [indx,tf] = listdlg('SelectionMode','single','ListString',name);
                drawnow;
                figure(app.UIFigure)
%                 for ii =1:length(IG)
%                     for jj=1:length(IG(ii).Datasets)
%                         name=char(IG(ii).Datasets(jj).Name);
%                             if isequal(name,char(datasets(indx)))
%                                 Group = IG(ii).Name;
%                                 Dataset = IG(ii).Datasets(jj).Name;
%                             end
%                     end
%                 end
                Group = group{indx};
                Dataset = name{indx};
                F = h5read([path file],['/' Group '/' Dataset]);
                app.leq{1,channel}=double(F);
                drawnow;
                figure(app.UIFigure)
                app.LeqMeasurementsPanel.Children(end-(channel*2-1)).Color = 'g';
            end 
            app.loadpath = path;
        end
    end
           

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           [directory,~,~] = fileparts(mfilename('fullpath'));
           cd(directory);
           addpath('./libs');
        end

        % Value changed function: EmailEditField
        function EmailEditFieldValueChanged(app, event)
            Email = app.EmailEditField.Value;
            p = max(strfind(Email,'.'));
            at = strfind(Email,'@');
            if ~isempty(p) && ~isempty(at) && at<p
                app.Email = Email;
                app.Lamp_Email.Color = 'g';
            else
                errordlg('The emailadress, which you have entered is not valid. Please try again')
                app.Lamp_Email.Color = 'r';
            end
        end

        % Value changed function: CreationDateDatePicker
        function CreationDateDatePickerValueChanged(app, event)
            app.CreationDate = app.CreationDateDatePicker.Value;
            app.CreationDate = char(datetime(app.CreationDate,'Format','yyyy-MM-dd HH:mm:ss'));
            if ~isempty(app.creationtime)
                creationtime = datetime(app.TimeEditField.Value,"Format","HH:mm");
                creaHH = hour(creationtime);
                creamm = minute(creationtime);
                app.CreationDate = datetime(app.CreationDate) + hours(creaHH) + minutes(creamm);
                app.CreationDate = char(datetime(app.CreationDate,'Format','yyyy-MM-dd HH:mm:ss'));
            else
                app.CreationDate = char(datetime(app.CreationDate,'Format','yyyy-MM-dd HH:mm:ss'));
            end
            app.Lamp_CreationDate.Color = 'g';
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            app.creationtime = datetime(app.TimeEditField.Value,"Format","HH:mm");
            creationtime=app.creationtime;
            creaHH = hour(creationtime);
            creamm = minute(creationtime);
            if ~isempty(app.CreationDate)
            app.CreationDate = datetime(app.CreationDate) + hours(creaHH) + minutes(creamm);
            app.CreationDate = char(datetime(app.CreationDate,'Format','yyyy-MM-dd HH:mm:ss'));
            else
                errordlg('Please enter date.')
            end
%             disp(app.CreationDate)
        end

        % Value changed function: StartDateDatePicker
        function StartDateDatePickerValueChanged(app, event)
            app.StartDate = app.StartDateDatePicker.Value;
            app.StartDate = char(datetime(app.StartDate,'Format','yyyy-MM-dd HH:mm:ss'));
            if ~isempty(app.starttime)
                starttime = datetime(app.TimeEditField_2.Value,"Format","HH:mm");
                startHH = hour(starttime);
                startmm = minute(starttime);
                app.StartDate = datetime(app.StartDate) + hours(startHH) + minutes(startmm);
                app.StartDate = char(datetime(app.StartDate,'Format','yyyy-MM-dd HH:mm:ss'));
            else
                app.StartDate = char(datetime(app.StartDate,'Format','yyyy-MM-dd HH:mm:ss'));
            end
            app.Lamp_StartDate.Color = 'g';
        end

        % Value changed function: TimeEditField_2
        function TimeEditField_2ValueChanged(app, event)
            app.starttime = datetime(app.TimeEditField_2.Value,'InputFormat','HH:mm');
            starttime = app.starttime;
            startHH = hour(starttime);
            startmm = minute(starttime);
            if ~isempty(app.StartDate)
            app.StartDate = datetime(app.StartDate) + hours(startHH) + minutes(startmm);
            app.StartDate = char(datetime(app.StartDate,'Format','yyyy-MM-dd HH:mm:ss'));
            else
                errordlg('Please enter date.')
            end
        end

        % Value changed function: EndDateDatePicker
        function EndDateDatePickerValueChanged(app, event)
            app.EndDate = app.EndDateDatePicker.Value;
            app.EndDate = char(datetime(app.EndDate,'Format','yyyy-MM-dd HH:mm:ss'));
            if ~isempty(app.endtime)
                endtime = datetime(app.TimeEditField_3.Value,"Format","HH:mm");
                endHH = hour(endtime);
                endmm = minute(endtime);
                app.EndDate = datetime(app.EndDate) + hours(endHH) + minutes(endmm);
                app.EndDate = char(datetime(app.EndDate,'Format','yyyy-MM-dd HH:mm:ss'));
            else
                app.EndDate = char(datetime(app.EndDate,'Format','yyyy-MM-dd HH:mm:ss'));
            end
            app.Lamp_EndDate.Color = 'g';
        end

        % Value changed function: TimeEditField_3
        function TimeEditField_3ValueChanged(app, event)
            app.endtime = datetime(app.TimeEditField_3.Value,'InputFormat','HH:mm');
            endtime = app.endtime;
            endHH = hour(endtime);
            endmm = minute(endtime);
            if ~isempty(app.EndDate)
            app.EndDate = datetime(app.EndDate) + hours(endHH) + minutes(endmm);
            app.EndDate = char(datetime(app.EndDate,'Format','yyyy-MM-dd HH:mm:ss'));
            else
                errordlg('Please enter date.')
            end
        end

        % Value changed function: InstitutionEditField
        function InstitutionEditFieldValueChanged(app, event)
            if app.Lamp_Institution.Color == [0,1,0]
                app.Lamp_Institution.Color = 'r';
            end
            institution = app.InstitutionEditField.Value;
            clear C
            fileID = fopen('.\libs\RECO_Export_07-14-2021-01-14-31.csv');
            Q = textscan(fileID,'%s %s %s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=institution;
            ix=strfind(Q{1,3},matchstr);
            idx = find(~cellfun(@isempty,ix));
            C{1}='Select ...';
            for ii=1:length(idx)
                name=char(Q{1,3}(idx(ii)));
                C{ii+1} = name;
            end
            app.SelectDropDown.Items = C;
        end

        % Value changed function: SelectDropDown
        function SelectDropDownValueChanged(app, event)
            Institution = app.SelectDropDown.Value;
            fileID = fopen('.\libs\RECO_Export_07-14-2021-01-14-31.csv');
            Q = textscan(fileID,'%s %s %s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=Institution;
            [~,idx]=intersect(Q{1,3},matchstr);
%             ix=strfind(Q{1,3},matchstr);
%             idx = find(~cellfun(@isempty,ix));
            if length(idx)>1
                errordlg('More than one Institution with this name were found. Please try again or enter Institution code from: https://vocab.ices.dk/?ref=1398')
            else
                app.Institution = char(Q{1,2}(idx));
                app.Lamp_Institution.Color = 'g';
            end  
        end

        % Value changed function: ContactEditField
        function ContactEditFieldValueChanged(app, event)
            Contact = app.ContactEditField.Value;
            p = max(strfind(Contact,'.'));
            at = strfind(Contact,'@');
            if ~isempty(p) && ~isempty(at) && at<p
                app.Contact = Contact;
                app.Lamp_Contact.Color = 'g';
            else
                errordlg('The emailadress, which you have entered is not valid. Please try again')
                app.Lamp_Contact.Color = 'r';
            end
        end

        % Value changed function: CountryCodeDropDown
        function CountryCodeDropDownValueChanged(app, event)
            app.CountryCode = app.CountryCodeDropDown.Value;
            app.Lamp_CountryCode.Color = 'g';
        end

        % Value changed function: StationCodeEditField
        function StationCodeEditFieldValueChanged(app, event)

            StationCode = app.StationCodeEditField.Value;
            clear C
            fileID = fopen('.\libs\StationCode.csv');
            Q = textscan(fileID,'%s %s %s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=StationCode;
            ix=strfind(Q{1,3},matchstr);
            idx = find(~cellfun(@isempty,ix));
            C{1}='Select ...';
            for ii=1:length(idx)
                name=char(Q{1,3}(idx(ii)));
                C{ii+1} = name;
            end
            app.SelectDropDown_StationCode.Items = C;
        end

        % Value changed function: SelectDropDown_StationCode
        function SelectDropDown_StationCodeValueChanged(app, event)
            StationCode = app.SelectDropDown_StationCode.Value;
            if app.Lamp_StationCode.Color == [0,1,0]
                app.Lamp_StationCode.Color = 'r';
            else
            if isequal(StationCode, 'Select ...') 
                app.Lamp_HydrophoneType.Color = 'r';
            else
            fileID = fopen('.\libs\StationCode.csv');
            Q = textscan(fileID,'%s %s %s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=StationCode;
            [~,idx]=intersect(Q{1,3},matchstr);
%             ix=strfind(Q{1,3},matchstr);
%             idx = find(~cellfun(@isempty,ix));
            if length(idx)>1
                errordlg('More than one Station with this station code were found. Please try again or enter station code from: https://vocab.ices.dk/?ref=1399')
            else
                app.StationCode = char(Q{1,2}(idx));
                app.Lamp_StationCode.Color = 'g';
            end
            end  
            end
        end

        % Value changed function: HydrophoneTypeDropDown
        function HydrophoneTypeDropDownValueChanged(app, event)
            HydrophoneType = app.HydrophoneTypeDropDown.Value;
            if isequal(HydrophoneType, 'Select ...') 
                app.Lamp_HydrophoneType.Color = 'r';
            else
            fileID = fopen('.\libs\HydrophoneType.csv');
            C = textscan(fileID,'%s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=HydrophoneType;
            [~,idx]=intersect(C{1,3},matchstr);
%             ix=strfind(C{1,3},matchstr);
%             idx = find(~cellfun(@isempty,ix));
            if length(idx)>1
                errordlg('More than one Hydrophone with this name were found. Please report incostency in Hydrophone type vocabulary to ICES (accessions@ices.dk).')
            else
                app.HydrophoneType =  C{1,2}(idx);
                app.Lamp_HydrophoneType.Color = 'g';
            end   
            end
        end

        % Value changed function: HydrophoneSerialNumberEditField
        function HydrophoneSerialNumberEditFieldValueChanged(app, event)
            app.HydrophoneSerialNumber = app.HydrophoneSerialNumberEditField.Value;
            app.Lamp_HydrophoneSerialNumber.Color = 'g';    
        end

        % Value changed function: RecorderTypeDropDown
        function RecorderTypeDropDownValueChanged(app, event)
            RecorderType = app.RecorderTypeDropDown.Value;
            if isequal(RecorderType, 'Select ...') 
                app.Lamp_RecorderType.Color = 'r';
            else
            fileID = fopen('.\libs\RecorderType.csv');
            C = textscan(fileID,'%s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=RecorderType;
            [~,idx]=intersect(C{1,3},matchstr);
            if length(idx)>1
                errordlg('More than one Recorder with this name were found. Please report incostency in Recorder type vocabulary to ICES (accessions@ices.dk).')
            else
                app.RecorderType =  C{1,2}(idx);
                app.Lamp_RecorderType.Color = 'g';
            end   
            end
        end

        % Value changed function: RecorderSerialNumberEditField
        function RecorderSerialNumberEditFieldValueChanged(app, event)
            app.RecorderSerialNumber = app.RecorderSerialNumberEditField.Value;
            app.Lamp_RecorderSerialNumber.Color = 'g'; 
        end

        % Value changed function: MeasurementHeightEditField
        function MeasurementHeightEditFieldValueChanged(app, event)
            app.MeasurementHeight = str2num(app.MeasurementHeightEditField.Value);
            app.Lamp_MeasurementHeight.Color = 'g';
        end

        % Value changed function: MeasurementPurposeDropDown
        function MeasurementPurposeDropDownValueChanged(app, event)
            MeasurementPurpose = app.MeasurementPurposeDropDown.Value;
            if isequal(MeasurementPurpose, 'Select ...') 
                app.Lamp_MeasurementPurpose.Color = 'r';
            else
            fileID = fopen('.\libs\MeasurementPurpose.csv');
            C = textscan(fileID,'%s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=MeasurementPurpose;
            [~,idx]=intersect(C{1,3},matchstr);
%             ix=strfind(C{1,3},matchstr);
%             idx = find(~cellfun(@isempty,ix));
            if length(idx)>1
                errordlg('More than one ICES - code were found for this measurement purpose. Please report incostency in Recorder type vocabulary to ICES (accessions@ices.dk).')
            else
                app.MeasurementPurpose =  C{1,2}(idx);
                app.Lamp_MeasurementPurpose.Color = 'g';
            end   
            end
        end

        % Value changed function: MeasurementSetupDropDown
        function MeasurementSetupDropDownValueChanged(app, event)
            MeasurementSetup = app.MeasurementSetupDropDown.Value;
            if isequal(MeasurementSetup, 'Select ...') 
                app.Lamp_MeasurementSetup.Color = 'r';
            else
            fileID = fopen('.\libs\MeasurementSetup.csv');
            C = textscan(fileID,'%s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=MeasurementSetup;
            [~,idx]=intersect(C{1,3},matchstr);
%             ix=strfind(C{1,3},matchstr);
%             idx = find(~cellfun(@isempty,ix));
            if length(idx)>1
                errordlg('More than one ICES - code were found for this measurement setup. Please report incostency in Recorder type vocabulary to ICES (accessions@ices.dk).')
            else
                app.MeasurementSetup =  C{1,2}(idx);
                app.Lamp_MeasurementSetup.Color = 'g';
            end   
            end
        end

        % Value changed function: RigDesignDropDown
        function RigDesignDropDownValueChanged(app, event)
            RigDesign = app.RigDesignDropDown.Value;
            if isequal(RigDesign, 'Select ...') 
                app.Lamp_RigDesign.Color = 'r';
            else
            fileID = fopen('.\libs\RigDesign.csv');
            C = textscan(fileID,'%s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=RigDesign;
            [~,idx]=intersect(C{1,3},matchstr);
%             ix=strfind(C{1,3},matchstr);
%             idx = find(~cellfun(@isempty,ix));
            if length(idx)>1
                errordlg('More than one ICES - code were found for this rig design. Please report incostency in Recorder type vocabulary to ICES (accessions@ices.dk).')
            else
                app.RigDesign =  C{1,2}(idx);
                app.Lamp_RigDesign.Color = 'g';
%                 disp(app.RigDesign)
            end   
            end
        end

        % Value changed function: FrequencyCountSpinner
        function FrequencyCountSpinnerValueChanged(app, event)
            app.FrequencyCount = cast(app.FrequencyCountSpinner.Value,'uint64');
            app.Lamp_FrequencyCount.Color = 'g';
        end

        % Callback function
        function FrequencyIndexEditFieldValueChanged(app, event)
            value = app.FrequencyIndexEditField.Value;
            app.Lamp_FrequencyIndex.Color = 'g';
        end

        % Value changed function: 
        % consecutiveThirdOctaveBandsCheckBox
        function consecutiveThirdOctaveBandsCheckBoxValueChanged(app, event)
            value = app.consecutiveThirdOctaveBandsCheckBox.Value;
            if value == 1
                set(app.LowestTOBDropDown, 'Enable', 'on')
                set(app.HighestTOBDropDown, 'Enable', 'on')
                set(app.FrequencyIndexEditField, 'Enable', 'off')
                set(app.FrequencyIndexEditField, 'Editable', 'off')
                set(app.fileButton, 'Enable', 'off')               
                app.individualFrequencyBandsCheckBox.Value = 0;
                app.LoadFrequencyBandsfromCheckBox.Value = 0;
            else
                set(app.LowestTOBDropDown, 'Enable', 'off')
                set(app.HighestTOBDropDown, 'Enable', 'off')
            end
        end

        % Value changed function: individualFrequencyBandsCheckBox
        function individualFrequencyBandsCheckBoxValueChanged(app, event)
            value = app.individualFrequencyBandsCheckBox.Value;
            if value == 0
                set(app.FrequencyIndexEditField, 'Enable', 'off')
            else
                set(app.FrequencyIndexEditField, 'Enable', 'on')
                set(app.FrequencyIndexEditField, 'Editable', 'on')
                set(app.LowestTOBDropDown, 'Enable', 'off')
                set(app.HighestTOBDropDown, 'Enable', 'off')
                set(app.fileButton, 'Enable', 'off')
                app.consecutiveThirdOctaveBandsCheckBox.Value = 0;
                app.LoadFrequencyBandsfromCheckBox.Value = 0;
            end
        end

        % Value changed function: LoadFrequencyBandsfromCheckBox
        function LoadFrequencyBandsfromCheckBoxValueChanged(app, event)
            value = app.LoadFrequencyBandsfromCheckBox.Value;
            if value == 0
                set(app.fileButton, 'Enable', 'off')
            else
                set(app.fileButton, 'Enable', 'on')
                set(app.LowestTOBDropDown, 'Enable', 'off')
                set(app.HighestTOBDropDown, 'Enable', 'off')
                set(app.FrequencyIndexEditField, 'Enable', 'off')
                app.consecutiveThirdOctaveBandsCheckBox.Value = 0;
                app.individualFrequencyBandsCheckBox.Value = 0;
            end
        end

        % Value changed function: LowestTOBDropDown
        function LowestTOBDropDownValueChanged(app, event)
            value = str2num(app.LowestTOBDropDown.Value);
            freqs=load('./libs/freqs.mat');
            freqs=freqs.freqs;
            [~,x] = min(abs(freqs-value));
            if ~isempty(app.fi_u)
                [~,y] = min(abs(app.fi_u-freqs));
                app.fi = double(freqs(x:y));
                app.Lamp_FrequencyIndex.Color = 'g';
            end
            app.fi_l = freqs(x);
            clear freqs
        end

        % Value changed function: HighestTOBDropDown
        function HighestTOBDropDownValueChanged(app, event)
            value = str2num(app.HighestTOBDropDown.Value);
            freqs=load('./libs/freqs.mat');
            freqs=freqs.freqs;
            [~,y] = min(abs(value-freqs));
            if ~isempty(app.fi_l)
                [~,x] = min(abs(app.fi_l-freqs));
                app.fi = double(freqs(x:y));
                app.Lamp_FrequencyIndex.Color = 'g';
            end
            app.fi_u = freqs(y);  
            clear freqs
        end

        % Value changed function: FrequencyIndexEditField
        function FrequencyIndexEditFieldValueChanged2(app, event)
            value = app.FrequencyIndexEditField.Value;
            value = str2num(value);
            app.fi = double(value);
            app.Lamp_FrequencyIndex.Color = 'g';
        end

        % Button pushed function: fileButton
        function fileButtonPushed(app, event)
            if ~isempty(app.loadpath)
                lastdir = app.loadpath;
            else
                lastdir = fileparts(mfilename('fullpath'));
            end
            [file,path] = uigetfile({'*.h5;*.mat;*.csv','Data Files (*.h5,*.mat,*.csv)';'*.*','All Files (*.*)'}, ...
            'Select File',lastdir);   
            drawnow;
            figure(app.UIFigure)          
            p = max(strfind(file,'.'));
            type = file(p+1:end);
            if isequal(type,'mat')
                F = load([path file]);
                F = struct2cell(F);
                F = cell2mat(F);
                app.fi = double(F);
                app.Lamp_FrequencyIndex.Color = 'g';
            elseif isequal(type,'csv')
                F = importdata([path file]);
                app.fi = double(F);
                app.Lamp_FrequencyIndex.Color = 'g';
            elseif isequal(type,'h5')
%                 I=h5info([path file]);
%                 IG = I.Groups;
%                 for ii =1:length(IG)
%                     for jj=1:length(IG(ii).Datasets)
%                         name{ii,jj}=IG(ii).Datasets(jj).Name;
%                     end
%                 end
                 I=h5info([path file]);
                 %% datasets, die in der ersten Ebene liegen
                 idx=1;
                 for ii =1:length(I.Datasets)
                        name{idx}=I.Datasets(ii).Name;
                        group{idx}=I.Name;
                        idx=idx+1;
                 end
                 %% check wie viele Gruppen gibt es
                 if length(I.Groups)>=1
                    for ii=1:length(I.Groups)
                        for jj=1:length(I.Groups(ii).Datasets)
                            name{idx}= I.Groups(ii).Datasets(jj).Name;
                            group{idx}= I.Groups(ii).Name;
                            idx=idx+1;
                        end
                        if length(I.Groups(ii).Groups)>=1
                            for kk=1:length(I.Groups(ii).Groups)
                                for ll=1:length(I.Groups(ii).Groups(kk).Datasets)
                                    name{idx}= I.Groups(ii).Groups(kk).Datasets(ll).Name;
                                    group{idx}= I.Groups(ii).Groups(kk).Name;
                                    idx=idx+1;
                                end
                            end
                        end
                    end
                 end
%                 emptyCells = cellfun(@isempty,name);
%                 datasets=name(emptyCells==0);
                [indx,~] = listdlg('SelectionMode','single','ListString',name);
                drawnow;
                figure(app.UIFigure)
%                 for ii =1:length(IG)
%                     for jj=1:length(IG(ii).Datasets)
%                         name=char(IG(ii).Datasets(jj).Name);
%                             if isequal(name,char(name(indx)))
%                                 Group = IG(ii).Name;
%                                 Dataset = IG(ii).Datasets(jj).Name;
%                             end
%                     end
%                 end
                Group = group{indx};
                Dataset = name{indx};
                F = h5read([path file],['/' Group '/' Dataset]);
                app.fi=double(F);
                drawnow;
                figure(app.UIFigure)
                app.Lamp_FrequencyIndex.Color = 'g';
                app.loadpath = path;
            end
        end

        % Value changed function: FrequencyUnitDropDown
        function FrequencyUnitDropDownValueChanged(app, event)
            value = app.FrequencyUnitDropDown.Value;
            app.fu = 'Hz';
            app.Lamp_FrequencyUnit.Color = 'g';
        end

        % Value changed function: ChannelCountSpinner
        function ChannelCountSpinnerValueChanged(app, event)
            value = app.ChannelCountSpinner.Value;
            if ~isempty(app.New)
                if length(app.New.button)> value
                    for ii=value+1:length(app.New.button)
                        delete(app.New.button{1,ii});
                        delete(app.New.lamp{1,ii});      
                    end
                end
            end
            app.ChannelCount = cast(value,'uint64');
            app.Lamp_ChannelCount.Color = 'g';
%             C{1} = 'Select ...';
%             for ii = 1:value
%                 name=num2str(ii);
%                 C{ii+1} = name;
%             end
% %             app.SelectChannelDropDown.Items=C;
% %             set(app.SelectChannelDropDown, 'Enable', 'on')
if value==1
    start =1;
else
    start = length(app.New.button)+1;
end
            for ii=start:value
            app.New.button{1,ii} = uibutton(app.LeqMeasurementsPanel,'push','Text',['Leq Measurements of Channel ' num2str(ii)],'Position',[50,380-23*ii, 200, 22]);
            app.New.lamp{1,ii} = uilamp(app.LeqMeasurementsPanel,"Color",'r',"Position",[10,380-23*ii, 20, 20]);
            app.New.button{1,ii}.ButtonPushedFcn = @app.leqbuttondown;
            end
        end

        % Value changed function: MeasurementTotalNoEditField
        function MeasurementTotalNoEditFieldValueChanged(app, event)
            value = app.MeasurementTotalNoEditField.Value;
            app.MeasurementTotalNo = cast(value,'uint64');
            app.Lamp_MeasurementTotalNo.Color = 'g';
        end

        % Value changed function: MeasurementUnitDropDown
        function MeasurementUnitDropDownValueChanged(app, event)
            value = app.MeasurementUnitDropDown.Value;
            app.MeasurementUnit = 'SPL';
            app.Lamp_MeasurementUnit.Color = 'g';
        end

        % Value changed function: AveragingTimeSpinner
        function AveragingTimeSpinnerValueChanged(app, event)
            value = app.AveragingTimeSpinner.Value;
            app.AveragingTime = cast(value,'uint64')
            app.Lamp_AveragingTime.Color = 'g';
        end

        % Value changed function: ProcessingAlgorithmDropDown
        function ProcessingAlgorithmDropDownValueChanged(app, event)
            ProcessingAlgorithm = app.ProcessingAlgorithmDropDown.Value;  
            if isequal(ProcessingAlgorithm, 'Select ...') 
                app.Lamp_ProcessingAlgorithm.Color = 'r';
            else
            fileID = fopen('.\libs\ProcessingAlgorithm.csv');
            C = textscan(fileID,'%s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=ProcessingAlgorithm;
            [~,idx]=intersect(C{1,3},matchstr);
%             ix=strfind(C{1,3},matchstr);
%             idx = find(~cellfun(@isempty,ix));
            if length(idx)>1
                errordlg('More than one code were found for this processing algorithm. Please report incostency in Processing Algorithm vocabulary to ICES (accessions@ices.dk).')
            else
                app.ProcessingAlgorithm =  C{1,2}(idx);
                app.Lamp_ProcessingAlgorithm.Color = 'g';
            end   
            end
            
        end

        % Button pushed function: CreateUUIDButton
        function CreateUUIDButtonPushed(app, event)
            UUID = char(java.util.UUID.randomUUID);
            app.DataUUIDEditField.Value = UUID;
            app.UUID = UUID;
            app.Lamp_DataUUID.Color = 'g';
        end

        % Value changed function: DatasetVersionSpinner
        function DatasetVersionSpinnerValueChanged(app, event)
            value = app.DatasetVersionSpinner.Value;
            app.DatasetVersion = num2str(value);
            app.Lamp_DatasetVersion.Color = 'g';
        end

        % Value changed function: CalibrationProcedureDropDown
        function CalibrationProcedureDropDownValueChanged(app, event)
            CalibrationProcedure = app.CalibrationProcedureDropDown.Value;
            if isequal(CalibrationProcedure, 'Select ...') 
                app.Lamp_CalibrationProcedure.Color = 'r';
            else
            fileID = fopen('.\libs\CalibrationProcedure.csv');
            C = textscan(fileID,'%s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=CalibrationProcedure;
            [~,idx]=intersect(C{1,3},matchstr);
%             ix=strfind(C{1,3},matchstr);
%             idx = find(~cellfun(@isempty,ix));
            if length(idx)>1
                errordlg('More than one code were found for this calibration procedure. Please report incostency in Calibration Procedure vocabulary to ICES (accessions@ices.dk).')
            else
                app.CalibrationProcedure =  C{1,2}(idx);
                app.Lamp_CalibrationProcedure.Color = 'g';
            end   
            end
        end

        % Value changed function: CalibrationDatetimeDatePicker
        function CalibrationDatetimeDatePickerValueChanged(app, event)
            app.CalibrationDate = app.CalibrationDatetimeDatePicker.Value;
            app.CalibrationDate = char(datetime(app.CalibrationDate,'Format','yyyy-MM-dd HH:mm:ss'));
            if ~isempty(app.calibrationtime)
                calibrationtime = datetime(app.TimeEditField_4,"Format","HH:mm");
                caliHH =hour(calibrationtime);
                calimm =minute(calibrationtime);
                app.CalibrationDate = datetime(app.CalibrationDate)+hours(caliHH)+minutes(calimm);
                app.CalibrationDate = char(datetime(app.CreationDate,'Format','yyyy-MM-dd HH:mm:ss'))
            else
                app.CalibrationDate = char(datetime(app.CalibrationDate,'Format','yyyy-MM-dd HH:mm:ss'));
            end
            app.Lamp_CalibrationDatetime.Color = 'g';
        end

        % Value changed function: TimeEditField_4
        function TimeEditField_4ValueChanged(app, event)
            calibrationtime = datetime(app.TimeEditField_4.Value,'InputFormat','HH:mm');
            caliHH = hour(calibrationtime);
            calimm = minute(calibrationtime);
            if ~isempty('app.CalibrationDate')
            app.CalibrationDate = datetime(app.CalibrationDate) + hours(caliHH) + minutes(calimm);
            app.CalibrationDate = char(datetime(app.CalibrationDate,'Format','yyyy-MM-dd HH:mm:ss'));
            else
                errordlg('Please enter date.')
            end

        end

        % Value changed function: CommentsEditField
        function CommentsEditFieldValueChanged(app, event)
            value = app.CommentsEditField.Value;
            app.comments = value;
            app.Lamp_Comments.Color = 'g';
        end

        % Callback function
        function SelectChannelDropDownValueChanged(app, event)
            value = app.SelectChannelDropDown.Value;
            app.ActCH = value;
            app.LeqMeasurementsofChannel1Label.Text = ['Leq Measurements of Channel ', value];
            app.SelectLeqMeasurementsofChannel1Button.Text = ['Select LeqMeasurements of Channel ', value];
            set(app.SelectLeqMeasurementsofChannel1Button, 'Enable', 'on')
        end

        % Button pushed function: SelectDatetimeVectorButton
        function SelectDatetimeVectorButtonPushed(app, event)
            if ~isempty(app.loadpath)
                lastdir = app.loadpath;
            else
                lastdir = fileparts(mfilename('fullpath'));
            end
            [file,path] = uigetfile({'*.h5;*.mat;*.csv','Data Files (*.h5,*.mat,*.csv)';'*.*','All Files (*.*)'}, ...
            'Select File',lastdir); 
            drawnow;
            figure(app.UIFigure)          
            p = max(strfind(file,'.'));
            type = file(p+1:end);
            if isequal(type,'mat')
                F = load([path file]);
                F = struct2cell(F);
                app.datetimeindex = F;
                app.Lamp_DateTime.Color = 'g';
            elseif isequal(type,'csv')
                F = importdata([path file]);
                app.datetimeindex = F;
                app.Lamp_DateTime.Color = 'g';
            elseif isequal(type,'h5')
                I=h5info([path file]);
%                 IG = I.Groups;
%                 for ii =1:length(IG)
%                     for jj=1:length(IG(ii).Datasets)
%                         name{ii,jj}=IG(ii).Datasets(jj).Name;
%                     end
%                 end
%                 emptyCells = cellfun(@isempty,name);
%                 datasets=name(emptyCells==0);
 I=h5info([path file]);
 
 %% datasets, die in der ersten Ebene liegen
                 idx=1;
                 for ii =1:length(I.Datasets)
                        name{idx}=I.Datasets(ii).Name;
                        group{idx}=I.Name;
                        idx=idx+1;
                 end
                 %% check wie viele Gruppen gibt es
                 if length(I.Groups)>=1
                    for ii=1:length(I.Groups)
                        for jj=1:length(I.Groups(ii).Datasets)
                            name{idx}= I.Groups(ii).Datasets(jj).Name;
                            group{idx}= I.Groups(ii).Name;
                            idx=idx+1;
                        end
                        if length(I.Groups(ii).Groups)>=1
                            for kk=1:length(I.Groups(ii).Groups)
                                for ll=1:length(I.Groups(ii).Groups(kk).Datasets)
                                    name{idx}= I.Groups(ii).Groups(kk).Datasets(ll).Name;
                                    group{idx}= I.Groups(ii).Groups(kk).Name;
                                    idx=idx+1;
                                end
                            end
                        end
                    end
                 end
                [indx,~] = listdlg('SelectionMode','single','ListString',name);
                drawnow;
                figure(app.UIFigure)
%                 for ii =1:length(IG)
%                     for jj=1:length(IG(ii).Datasets)
%                         name=char(IG(ii).Datasets(jj).Name);
%                             if isequal(name,char(datasets(indx)))
%                                 Group = IG(ii).Name;
%                                 Dataset = IG(ii).Datasets(jj).Name;
%                             end
%                     end
%                 end
                Group = group{indx};
                Dataset = name{indx};
                F = h5read([path file],['/' Group '/' Dataset]);
                if datetime(F(1),'ConvertFrom','datenum') > datetime(2010,1,1) && datetime(F(1),'ConvertFrom','datenum') < datetime(2100,1,1)
                    tp = 'datenum';
                elseif datetime(F(1),'ConvertFrom','juliandate') > datetime(2010,1,1) && datetime(F(1),'ConvertFrom','juliandate') < datetime(2100,1,1)
                    tp = 'juliandate';
                elseif datetime(F(1),'ConvertFrom','excel') > datetime(2010,1,1) && datetime(F(1),'ConvertFrom','excel') < datetime(2100,1,1)
                    tp = 'excel';
                elseif datetime(F(1),'ConvertFrom','posixtime') > datetime(2010,1,1) && datetime(F(1),'ConvertFrom','posixtime') < datetime(2100,1,1)
                    tp = 'posixtime';
                else
                    errordlg('Datetime type not recognized')
                end
                F=cellstr(datetime(F,'ConvertFrom',tp,'Format','yyyy-MM-dd HH:mm:ss'));
                app.datetimeindex=F;         
                drawnow;
                figure(app.UIFigure)
                app.Lamp_DateTime.Color = 'g';
            end


            app.loadpath = path;
        end

        % Callback function
        function SelectLeqMeasurementsofChannel1ButtonPushed(app, event)
            %% get channel
            channel = str2num(app.ActCH);
            %% get Leqs from file
            [file,path] = uigetfile({'*.mat';'*.csv';'*.h5'},...
                          'File Selector'); 
            drawnow;
            figure(app.UIFigure)
            p = max(strfind(file,'.'));
            type = file(p+1:end);
            if isequal(type,'mat')
                F = load([path file]);
                F = struct2cell(F);
                F = cell2mat(F);
                app.leq{1,channel} = double(F);
                app.Lamp_leq.Color = 'g';
            elseif isequal(type,'csv')
                F = importdata([path file]);
                app.leq{1,channel} = double(F);
                app.Lamp_leq.Color = 'g';
            elseif isequal(type,'h5')
                I=h5info([path file]);
                IG = I.Groups;
                for ii =1:length(IG)
                    for jj=1:length(IG(ii).Datasets)
                        name{ii,jj}=IG(ii).Datasets(jj).Name;
                    end
                end
                emptyCells = cellfun(@isempty,name);
                datasets=name(emptyCells==0);
                [indx,tf] = listdlg('SelectionMode','single','ListString',datasets);
                drawnow;
                figure(app.UIFigure)
                for ii =1:length(IG)
                    for jj=1:length(IG(ii).Datasets)
                        name=char(IG(ii).Datasets(jj).Name);
                            if isequal(name,char(datasets(indx)))
                                Group = IG(ii).Name;
                                Dataset = IG(ii).Datasets(jj).Name;
                            end
                    end
                end
                F = h5read([path file],['/' Group '/' Dataset]);
                app.leq{1,channel}=double(F);
                drawnow;
                figure(app.UIFigure)
                app.Lamp_leq.Color = 'g';
            end
            
            
        end

        % Button pushed function: WritetoICESh5fileButton
        function WritetoICESh5fileButtonPushed(app, event)
            %% where
            [file,path] = uiputfile('*.h5', 'Save as:');
            drawnow;
            figure(app.UIFigure);
            %% Create the dataset:
            
dset = struct(...
    'FileInformation', struct( ...
    'Email', app.Email, ...
    'CreationDate', app.CreationDate, ...
    'StartDate', app.StartDate, ...
    'EndDate',app.EndDate, ...
    'Institution',app.Institution, ...
    'Contact', app.Contact, ...
    'CountryCode', app.CountryCode, ...
    'StationCode', app.StationCode), ...
    'Metadata', struct( ...
    'HydrophoneType', app.HydrophoneType, ...
    'HydrophoneSerialNumber', app.HydrophoneSerialNumber, ...
    'RecorderType', app.RecorderType, ...
    'RecorderSerialNumber', app.RecorderSerialNumber, ...
    'MeasurementHeight', app.MeasurementHeight, ...
    'MeasurementPurpose', app.MeasurementPurpose, ...
    'MeasurementSetup',app.MeasurementSetup, ...
    'RigDesign', app.RigDesign, ...
    'FrequencyCount', app.FrequencyCount, ...
    'FrequencyIndex', app.fi, ...
    'FrequencyUnit', app.fu, ...
    'ChannelCount', app.ChannelCount, ...
    'MeasurementTotalNo', app.MeasurementTotalNo, ...
    'MeasurementUnit', app.MeasurementUnit, ...
    'AveragingTime', app.AveragingTime, ...
    'ProcessingAlgorithm', app.ProcessingAlgorithm, ...
    'DataUUID', app.UUID, ...
    'DatasetVersion', app.DatasetVersion, ...
    'CalibrationProcedure', app.CalibrationProcedure, ...
    'CalibrationDateTime', app.CalibrationDate, ...
    'Comments', app.comments), ...
    'Data', struct( ...
    'LeqMeasurementsOfChannel1', app.leq{1,1}))
    
%% creates and writes data to hdf5 file (file must not exits at time of function call)
name = [path file];
matlab_write_recursive_hdf5(name, '', dset);
hdf5write(name,'/Data/DateTime',app.datetimeindex,'WriteMode', 'append');

if length(app.leq)>1
    for ii=2:length(app.leq)
        hdf5write(name,['/Data/LeqMeasurementsOfChannel' num2str(ii)],app.leq{1,ii},'WriteMode', 'append');
    end
end
app.saveh5name = name;


% % end
% end
% for ii =1:length(app.leq)
%     hdf5write(name,['/Data/LeqMeasurementsOfChannel' num2str(ii)],app.leq{1,ii},'WriteMode', 'append')
% end
% app.saveh5name = name;

        end

        % Button pushed function: MovealldatatoMATLABworkspaceButton
        function MovealldatatoMATLABworkspaceButtonPushed(app, event)
dset = struct(...
    'FileInformation', struct( ...
    'Email', app.Email, ...
    'CreationDate', app.CreationDate, ...
    'StartDate', app.StartDate, ...
    'EndDate',app.EndDate, ...
    'Institution',app.Institution, ...
    'Contact', app.Contact, ...
    'CountryCode', app.CountryCode, ...
    'StationCode', app.StationCode), ...
    'Metadata', struct( ...
    'HydrophoneType', app.HydrophoneType, ...
    'HydrophoneSerialNumber', app.HydrophoneSerialNumber, ...
    'RecorderType', app.RecorderType, ...
    'RecorderSerialNumber', app.RecorderSerialNumber, ...
    'MeasurementHeight', app.MeasurementHeight, ...
    'MeasurementPurpose', app.MeasurementPurpose, ...
    'MeasurementSetup',app.MeasurementSetup, ...
    'RigDesign', app.RigDesign, ...
    'FrequencyCount', app.FrequencyCount, ...
    'FrequencyIndex', app.fi, ...
    'FrequencyUnit', app.fu, ...
    'ChannelCount', app.ChannelCount, ...
    'MeasurementTotalNo', app.MeasurementTotalNo, ...
    'MeasurementUnit', app.MeasurementUnit, ...
    'AveragingTime', app.AveragingTime, ...
    'ProcessingAlgorithm', app.ProcessingAlgorithm, ...
    'DataUUID', app.UUID, ...
    'DatasetVersion', app.DatasetVersion, ...
    'CalibrationProcedure', app.CalibrationProcedure, ...
    'CalibrationDateTime', app.CalibrationDate, ...
    'Comments', app.comments), ...
    'Data', struct( ...
    'DateTime', cell2mat(app.datetimeindex), ...
    'LeqMeasurementsOfChannel1', app.leq{1,1}));

if length(app.leq)>1
    for ii =2:length(app.leq)
        newstruct = ['LeqMeasurementsOfChannel' num2str(ii)];
        dset.Data.(newstruct) = app.leq{1,ii};
    end
end

%     'DateTime', app.datetimeindex, ...
assignin('base','h5File',dset);
        end

        % Button pushed function: 
        % GotoICEScontinuousnoiseregistryButton
        function GotoICEScontinuousnoiseregistryButtonPushed(app, event)
            dos('explorer https://underwaternoise.ices.dk/continuous/manage/'); %Explorer
        end

        % Button pushed function: Viewh5filewithhdfviewButton
        function Viewh5filewithhdfviewButtonPushed(app, event)
            if ~isempty(app.saveh5name)
            winopen(app.saveh5name);
            else
                warndlg('No h5 file was exported.')
            end
        end

        % Menu selected function: 
        % SeeICEScontinuousnoisedataformatMenu
        function SeeICEScontinuousnoisedataformatMenuSelected(app, event)
            winopen('libs\ICES_format.pdf');
        end

        % Button pushed function: fromfileButton_2
        function fromfileButton_2Pushed(app, event)
%             [file,path] = uigetfile({'*.h5';'*.mat';'*.csv'},...
%                           'File Selector');   
            if ~isempty(app.loadpath)
                lastdir = app.loadpath;
            else
                lastdir = fileparts(mfilename('fullpath'));
            end
            [file,path] = uigetfile({'*.h5;*.mat;*.csv','Data Files (*.h5,*.mat,*.csv)';'*.*','All Files (*.*)'}, ...
            'Select File',lastdir); 
            drawnow;
            figure(app.UIFigure)          
            p = max(strfind(file,'.'));
            type = file(p+1:end);
            if isequal(type,'mat')
                F = load([path file]);
                F = struct2cell(F);
                F = cell2mat(F);
                app.MeasurementTotalNo = cast(F,'uint64');
                app.MeasurementTotalNoEditField.Value = cast(F,'uint64');
                app.Lamp_MeasurementTotalNo.Color = 'g';
            elseif isequal(type,'csv')
                F = importdata([path file]);
                app.MeasurementTotalNo = cast(F,'uint64');
                app.MeasurementTotalNoEditField.Value = cast(F,'uint64');
                app.Lamp_MeasurementTotalNo.Color = 'g';
            elseif isequal(type,'h5')
                 I=h5info([path file]);
                 %% datasets, die in der ersten Ebene liegen
                 idx=1;
                 for ii =1:length(I.Datasets)
                        name{idx}=I.Datasets(ii).Name;
                        group{idx}=I.Name;
                        idx=idx+1;
                 end
                 %% check wie viele Gruppen gibt es
                 if length(I.Groups)>=1
                    for ii=1:length(I.Groups)
                        for jj=1:length(I.Groups(ii).Datasets)
                            name{idx}= I.Groups(ii).Datasets(jj).Name;
                            group{idx}= I.Groups(ii).Name;
                            idx=idx+1;
                        end
                        if length(I.Groups(ii).Groups)>=1
                            for kk=1:length(I.Groups(ii).Groups)
                                for ll=1:length(I.Groups(ii).Groups(kk).Datasets)
                                    name{idx}= I.Groups(ii).Groups(kk).Datasets(ll).Name;
                                    group{idx}= I.Groups(ii).Groups(kk).Name;
                                    idx=idx+1;
                                end
                            end
                        end
                    end
                 end
                [indx,~] = listdlg('SelectionMode','single','ListString',name);
                drawnow;
                figure(app.UIFigure)
                Group = group{indx};
                Dataset = name{indx};
                F = h5read([path file],['/' Group '/' Dataset]);
                app.MeasurementTotalNo = cast(F,'uint64');
                app.MeasurementTotalNoEditField.Value = double(F);
                app.Lamp_MeasurementTotalNo.Color = 'g';
                drawnow;
                figure(app.UIFigure)
            end
        end

        % Button pushed function: fromfileButton
        function fromfileButtonPushed(app, event)
            if ~isempty(app.loadpath)
                lastdir = app.loadpath;
            else
                lastdir = fileparts(mfilename('fullpath'));
            end
            [file,path] = uigetfile({'*.h5;*.mat;*.csv','Data Files (*.h5,*.mat,*.csv)';'*.*','All Files (*.*)'}, ...
            'Select File',lastdir);   
            drawnow;
            figure(app.UIFigure)          
            p = max(strfind(file,'.'));
            type = file(p+1:end);
            if isequal(type,'mat')
                F = load([path file]);
                F = struct2cell(F);
                F = cell2mat(F);
            app.FrequencyCount = cast(F,'uint64');
            app.FrequencyCountSpinner.Value = cast(F,'uint64');
            app.Lamp_FrequencyCount.Color = 'g';
            elseif isequal(type,'csv')
                F = importdata([path file]);
            app.FrequencyCount = cast(F,'uint64');
            app.FrequencyCountSpinner.Value = cast(F,'uint64');
            app.Lamp_FrequencyCount.Color = 'g';
            elseif isequal(type,'h5')
                 I=h5info([path file]);
                 %% datasets, die in der ersten Ebene liegen
                 idx=1;
                 for ii =1:length(I.Datasets)
                        name{idx}=I.Datasets(ii).Name;
                        group{idx}=I.Name;
                        idx=idx+1;
                 end
                 %% check wie viele Gruppen gibt es
                 if length(I.Groups)>=1
                    for ii=1:length(I.Groups)
                        for jj=1:length(I.Groups(ii).Datasets)
                            name{idx}= I.Groups(ii).Datasets(jj).Name;
                            group{idx}= I.Groups(ii).Name;
                            idx=idx+1;
                        end
                        if length(I.Groups(ii).Groups)>=1
                            for kk=1:length(I.Groups(ii).Groups)
                                for ll=1:length(I.Groups(ii).Groups(kk).Datasets)
                                    name{idx}= I.Groups(ii).Groups(kk).Datasets(ll).Name;
                                    group{idx}= I.Groups(ii).Groups(kk).Name;
                                    idx=idx+1;
                                end
                            end
                        end
                    end
                 end
                [indx,~] = listdlg('SelectionMode','single','ListString',name);
                drawnow;
                figure(app.UIFigure)
                Group = group{indx};
                Dataset = name{indx};
                F = h5read([path file],['/' Group '/' Dataset]);
                app.FrequencyCount = cast(F,'uint64');
                app.FrequencyCountSpinner.Value = double(F);
                app.Lamp_FrequencyCount.Color = 'g';
                drawnow;
                figure(app.UIFigure)
                app.loadpath = path;
            end
            app.loadpath = path;
        end

        % Menu selected function: LoadPAMGuidecsvfileMenu
        function LoadPAMGuidecsvfileMenuSelected(app, event)
            if ~isempty(app.loadpath)
                lastdir = app.loadpath;
            else
                lastdir = fileparts(mfilename('fullpath'));
            end
            [file,path] = uigetfile({'*.csv','PAMGuide .csv files (*.csv)';'*.*','All Files (*.*)'}, ...
            'Select PAMGuide Output File',lastdir);   
            drawnow;
            figure(app.UIFigure)
            filename = [path file];
            F = load(filename);
            %% datetime
            t=cellstr(datetime(F(2:end,1),'ConvertFrom','datenum','Format','yyyy-MM-dd HH:mm:ss'));
            app.Lamp_DateTime.Color = 'g';
            app.datetimeindex = t;
            %% frequency index
            f=double(F(1,2:end));
            app.Lamp_FrequencyIndex.Color = 'g';
            app.fi = f;
            f_count = cast(length(f),'uint64');
            app.FrequencyCount = f_count;
            app.FrequencyCountSpinner.Value = double(f_count);
            app.Lamp_FrequencyCount.Color = 'g';
            %% channel
            ch_count = cast(1,'uint64');
            app.ChannelCountSpinner.Value = double(1);
            app.ChannelCount = cast(1,'uint64');
            app.Lamp_ChannelCount.Color = 'g';
            %% LeqMeasurements
            app.New.button{1,1} = uibutton(app.LeqMeasurementsPanel,'push','Text',['Leq Measurements of Channel 1'],'Position',[50,380-23, 200, 22]);
            app.New.lamp{1,1} = uilamp(app.LeqMeasurementsPanel,"Color",'g',"Position",[10,380-23, 20, 20]);
            app.New.button{1,1}.ButtonPushedFcn = @app.leqbuttondown;
            app.leq{1,1} = double(F(2:end,2:end));


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.LoadBSHh5fileMenu = uimenu(app.Menu);
            app.LoadBSHh5fileMenu.Text = 'Load BSH .h5 file';
            app.LoadPAMGuidecsvfileMenu = uimenu(app.Menu);
            app.LoadPAMGuidecsvfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPAMGuidecsvfileMenuSelected, true);
            app.LoadPAMGuidecsvfileMenu.Text = 'Load PAMGuide .csv file';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.SeeICEScontinuousnoisedataformatMenu = uimenu(app.HelpMenu);
            app.SeeICEScontinuousnoisedataformatMenu.MenuSelectedFcn = createCallbackFcn(app, @SeeICEScontinuousnoisedataformatMenuSelected, true);
            app.SeeICEScontinuousnoisedataformatMenu.Text = 'See ICES continuous noise data format';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileInformationTab = uitab(app.TabGroup);
            app.FileInformationTab.Title = 'File Information';
            app.EmailEditFieldLabel = uilabel(app.FileInformationTab);
            app.EmailEditFieldLabel.Text = 'Email';
            app.EmailEditField = uieditfield(app.FileInformationTab, 'text');
            app.EmailEditField.ValueChangedFcn = createCallbackFcn(app, @EmailEditFieldValueChanged, true);
            app.EmailEditField.Value = 'test@test.com';
            app.CreationDateDatePickerLabel = uilabel(app.FileInformationTab);
            app.CreationDateDatePickerLabel.Text = 'Creation Date';
            app.CreationDateDatePicker = uidatepicker(app.FileInformationTab);
            app.CreationDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @CreationDateDatePickerValueChanged, true);
            app.StartDateDatePickerLabel = uilabel(app.FileInformationTab);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.FileInformationTab);
            app.StartDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePickerValueChanged, true);
            app.EndDateDatePickerLabel = uilabel(app.FileInformationTab);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.FileInformationTab);
            app.EndDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EndDateDatePickerValueChanged, true);
            app.ContactEditFieldLabel = uilabel(app.FileInformationTab);
            app.ContactEditFieldLabel.Text = 'Contact';
            app.ContactEditField = uieditfield(app.FileInformationTab, 'text');
            app.ContactEditField.ValueChangedFcn = createCallbackFcn(app, @ContactEditFieldValueChanged, true);
            app.ContactEditField.Value = 'test@test.com';
            app.CountryCodeDropDownLabel = uilabel(app.FileInformationTab);
            app.CountryCodeDropDownLabel.Text = 'Country Code';
            app.CountryCodeDropDown = uidropdown(app.FileInformationTab);
            app.CountryCodeDropDown.Items = {'Select ...', 'DE', 'DK', 'EE', 'FI', 'LT', 'LV', 'PL', 'RU', 'SE'};
            app.CountryCodeDropDown.ValueChangedFcn = createCallbackFcn(app, @CountryCodeDropDownValueChanged, true);
            app.CountryCodeDropDown.Value = 'Select ...';
            app.TimeEditFieldLabel = uilabel(app.FileInformationTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.FileInformationTab, 'text');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.TimeEditField.Value = 'HH:mm';
            app.TimeEditField_2Label = uilabel(app.FileInformationTab);
            app.TimeEditField_2Label.Text = 'Time';
            app.TimeEditField_2 = uieditfield(app.FileInformationTab, 'text');
            app.TimeEditField_2.ValueChangedFcn = createCallbackFcn(app, @TimeEditField_2ValueChanged, true);
            app.TimeEditField_2.Value = 'HH:mm';
            app.TimeEditField_3Label = uilabel(app.FileInformationTab);
            app.TimeEditField_3Label.Text = 'Time';
            app.TimeEditField_3 = uieditfield(app.FileInformationTab, 'text');
            app.TimeEditField_3.ValueChangedFcn = createCallbackFcn(app, @TimeEditField_3ValueChanged, true);
            app.TimeEditField_3.Value = 'HH:mm';
            app.InstitutionEditFieldLabel = uilabel(app.FileInformationTab);
            app.InstitutionEditFieldLabel.Text = 'Institution';
            app.InstitutionEditField = uieditfield(app.FileInformationTab, 'text');
            app.InstitutionEditField.ValueChangedFcn = createCallbackFcn(app, @InstitutionEditFieldValueChanged, true);
            app.InstitutionEditField.Value = 'Search';
            app.Lamp_Email = uilamp(app.FileInformationTab);
            app.Lamp_CreationDate = uilamp(app.FileInformationTab);
            app.Lamp_StartDate = uilamp(app.FileInformationTab);
            app.Lamp_EndDate = uilamp(app.FileInformationTab);
            app.Lamp_Institution = uilamp(app.FileInformationTab);
            app.Lamp_Contact = uilamp(app.FileInformationTab);
            app.Lamp_CountryCode = uilamp(app.FileInformationTab);
            app.Lamp_StationCode = uilamp(app.FileInformationTab);
            app.SelectDropDownLabel = uilabel(app.FileInformationTab);
            app.SelectDropDownLabel.Text = 'Select';
            app.SelectDropDown = uidropdown(app.FileInformationTab);
            app.SelectDropDown.Items = {'Search first ...', ''};
            app.SelectDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDropDownValueChanged, true);
            app.SelectDropDown.Value = 'Search first ...';
            app.StationCodeEditFieldLabel = uilabel(app.FileInformationTab);
            app.StationCodeEditFieldLabel.Text = 'Station Code';
            app.StationCodeEditField = uieditfield(app.FileInformationTab, 'text');
            app.StationCodeEditField.ValueChangedFcn = createCallbackFcn(app, @StationCodeEditFieldValueChanged, true);
            app.StationCodeEditField.Value = 'Search';
            app.SelectDropDown_2Label = uilabel(app.FileInformationTab);
            app.SelectDropDown_2Label.Text = 'Select';
            app.SelectDropDown_StationCode = uidropdown(app.FileInformationTab);
            app.SelectDropDown_StationCode.Items = {'Search first ...', ''};
            app.SelectDropDown_StationCode.ValueChangedFcn = createCallbackFcn(app, @SelectDropDown_StationCodeValueChanged, true);
            app.SelectDropDown_StationCode.Value = 'Search first ...';
            app.Image = uiimage(app.FileInformationTab);
            app.Image.Tooltip = {'Creator of the HDF5 file/ who holds responsibility for data QA and'; 'creation of the submited hdf5 file.'};
            app.Image.ImageSource = 'info.png';
            app.Image_2 = uiimage(app.FileInformationTab);
            app.Image_2.Tooltip = {'Date of file creation. UTC DateTime in ISO 8601 format: YYYY-MMDDThh:'; 'mm[:ss] or YYYY-MM-DD hh:mm[:ss] . Seconds are optional.'; 'For example:'; '2020-04-01 11:36Z, or'; '2020-04-01 11:36:23Z'};
            app.Image_2.ImageSource = 'info.png';
            app.Image_3 = uiimage(app.FileInformationTab);
            app.Image_3.Tooltip = {'Measurement collection start date. UTC DateTime in ISO 8601'; 'format: YYYY-MM-DDThh:mm[:ss] or YYYY-MM-DD hh:mm[:ss].'; 'Seconds are optional.'; 'For example:'; '2020-04-01 11:36Z, or'; '2020-04-01 11:36:23Z'};
            app.Image_3.ImageSource = 'info.png';
            app.Image_4 = uiimage(app.FileInformationTab);
            app.Image_4.Tooltip = {'Measurement collection end date. UTC DateTime in ISO 8601'; 'format: YYYY-MM-DDThh:mm[:ss] or YYYY-MM-DD hh:mm[:ss].'; 'Seconds are optional.'; 'For example:'; '2020-04-01 11:36Z, or'; '2020-04-01 11:36:23Z'};
            app.Image_4.ImageSource = 'info.png';
            app.Image_5 = uiimage(app.FileInformationTab);
            app.Image_5.Tooltip = {'Institution which acquired the data. https://vocab.ices.dk/?ref=1398'};
            app.Image_5.ImageSource = 'info.png';
            app.Image_6 = uiimage(app.FileInformationTab);
            app.Image_6.Tooltip = {'Contact of all future external queries/who submits/holds'; 'responsibility for submission'};
            app.Image_6.ImageSource = 'info.png';
            app.Image_7 = uiimage(app.FileInformationTab);
            app.Image_7.Tooltip = {'https://vocab.ices.dk/?ref=337'};
            app.Image_7.ImageSource = 'info.png';
            app.Image_8 = uiimage(app.FileInformationTab);
            app.Image_8.Tooltip = {'The station code and its associated coordinates can be found in the'; 'ICES station dictionary'; ''; 'https://vocab.ices.dk/?ref=1399'; ''};
            app.Image_8.ImageSource = 'info.png';
            app.MetadataTab = uitab(app.TabGroup);
            app.MetadataTab.Title = 'Metadata';
            app.CreateUUIDButton = uibutton(app.MetadataTab, 'push');
            app.CreateUUIDButton.ButtonPushedFcn = createCallbackFcn(app, @CreateUUIDButtonPushed, true);
            app.CreateUUIDButton.Text = 'Create UUID';
            app.HydrophoneTypeDropDownLabel = uilabel(app.MetadataTab);
            app.HydrophoneTypeDropDownLabel.Text = 'Hydrophone Type';
            app.HydrophoneTypeDropDown = uidropdown(app.MetadataTab);
            app.HydrophoneTypeDropDown.Items = {'Select ...', 'Brüel & Kjaer 8103', 'Brüel & Kjaer 8104 ', 'Brüel & Kjaer 8105', 'Brüel & Kjaer 8106', 'COLMAR GP1516', 'DE-200', 'DE-600', 'DE-PRO', 'GeoSpectrum M14-360 ', 'GeoSpectrum M14-900', 'GeoSpectrum M15-360', 'GeoSpectrum M15-900', 'GeoSpectrum M36-100', 'GeoSpectrum M36-900', 'GeoSpectrum M5-360', 'GeoSpectrum M5-900', 'HTI-15-Min', 'HTI-90-UReftek 17', 'HTI-94-SSQ', 'HTI-96-Min', 'HTI-97-DA', 'HTI-99-HF', 'HTI-99-UHF', 'Integrated in recorder', 'Soundtrap external hydrophone', 'Sparton'};
            app.HydrophoneTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @HydrophoneTypeDropDownValueChanged, true);
            app.HydrophoneTypeDropDown.Value = 'Select ...';
            app.HydrophoneSerialNumberEditFieldLabel = uilabel(app.MetadataTab);
            app.HydrophoneSerialNumberEditFieldLabel.Text = 'Hydrophone Serial Number';
            app.HydrophoneSerialNumberEditField = uieditfield(app.MetadataTab, 'text');
            app.HydrophoneSerialNumberEditField.ValueChangedFcn = createCallbackFcn(app, @HydrophoneSerialNumberEditFieldValueChanged, true);
            app.RecorderTypeDropDownLabel = uilabel(app.MetadataTab);
            app.RecorderTypeDropDownLabel.Text = 'Recorder Type';
            app.RecorderTypeDropDown = uidropdown(app.MetadataTab);
            app.RecorderTypeDropDown.Items = {'Select ...', 'JASCO Amar', 'Loggerhead DSG', 'Loggerhead DSG-ST', 'Loggerhead LS1/LS2', 'Loggerhead Medusa Acoustic Drifter', 'Loggerhead SNAP', 'OceanInstruments SOUNDTRAP 300 HF', 'OceanInstruments SOUNDTRAP 300 STD', 'OceanInstruments SOUNDTRAP 500 HF', 'OceanInstruments SOUNDTRAP 500 STD', 'OceanSonics icListen 200kHz', 'RTSys EASDA14 320', 'RTSys Sylence-LP recorder', 'Wildlife SM2M', 'Wildlife SM2M+', 'Wildlife SM3M', 'Wildlife SM3M+', 'Wildlife SM4M'};
            app.RecorderTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @RecorderTypeDropDownValueChanged, true);
            app.RecorderTypeDropDown.Value = 'Select ...';
            app.RecorderSerialNumberEditFieldLabel = uilabel(app.MetadataTab);
            app.RecorderSerialNumberEditFieldLabel.Text = 'Recorder Serial Number';
            app.RecorderSerialNumberEditField = uieditfield(app.MetadataTab, 'text');
            app.RecorderSerialNumberEditField.ValueChangedFcn = createCallbackFcn(app, @RecorderSerialNumberEditFieldValueChanged, true);
            app.MeasurementHeightEditFieldLabel = uilabel(app.MetadataTab);
            app.MeasurementHeightEditFieldLabel.Text = 'Measurement Height';
            app.MeasurementHeightEditField = uieditfield(app.MetadataTab, 'text');
            app.MeasurementHeightEditField.ValueChangedFcn = createCallbackFcn(app, @MeasurementHeightEditFieldValueChanged, true);
            app.MeasurementPurposeDropDownLabel = uilabel(app.MetadataTab);
            app.MeasurementPurposeDropDownLabel.Text = 'Measurement Purpose';
            app.MeasurementPurposeDropDown = uidropdown(app.MetadataTab);
            app.MeasurementPurposeDropDown.Items = {'Select ...', 'Commissioned measurements', 'HELCOM monitoring', 'National monitoring', 'OSPAR monitoring', 'Other', 'Project', 'Research'};
            app.MeasurementPurposeDropDown.ValueChangedFcn = createCallbackFcn(app, @MeasurementPurposeDropDownValueChanged, true);
            app.MeasurementPurposeDropDown.Value = 'Select ...';
            app.MeasurementSetupDropDownLabel = uilabel(app.MetadataTab);
            app.MeasurementSetupDropDownLabel.Text = 'Measurement Setup';
            app.MeasurementSetupDropDown = uidropdown(app.MetadataTab);
            app.MeasurementSetupDropDown.Items = {'Select ...', 'Autonomous', 'Cable mounted', 'Combined'};
            app.MeasurementSetupDropDown.ValueChangedFcn = createCallbackFcn(app, @MeasurementSetupDropDownValueChanged, true);
            app.MeasurementSetupDropDown.Value = 'Select ...';
            app.RigDesignDropDownLabel = uilabel(app.MetadataTab);
            app.RigDesignDropDownLabel.Text = 'Rig Design';
            app.RigDesignDropDown = uidropdown(app.MetadataTab);
            app.RigDesignDropDown.Items = {'Select ...', 'Bottom frame', 'From vessel', 'Glider', 'Mooring with floating buoy', 'Other', 'Supended from surface', 'Vertical measurement chain'};
            app.RigDesignDropDown.ValueChangedFcn = createCallbackFcn(app, @RigDesignDropDownValueChanged, true);
            app.RigDesignDropDown.Value = 'Select ...';
            app.FrequencyCountSpinnerLabel = uilabel(app.MetadataTab);
            app.FrequencyCountSpinnerLabel.Text = 'Frequency Count';
            app.FrequencyCountSpinner = uispinner(app.MetadataTab);
            app.FrequencyCountSpinner.Limits = [1 48];
            app.FrequencyCountSpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencyCountSpinnerValueChanged, true);
            app.FrequencyCountSpinner.Value = 1;
            app.FrequencyUnitDropDownLabel = uilabel(app.MetadataTab);
            app.FrequencyUnitDropDownLabel.Text = 'Frequency Unit';
            app.FrequencyUnitDropDown = uidropdown(app.MetadataTab);
            app.FrequencyUnitDropDown.Items = {'Select ...', 'Hertz'};
            app.FrequencyUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencyUnitDropDownValueChanged, true);
            app.FrequencyUnitDropDown.Value = 'Select ...';
            app.ChannelCountSpinnerLabel = uilabel(app.MetadataTab);
            app.ChannelCountSpinnerLabel.Text = 'Channel Count';
            app.ChannelCountSpinner = uispinner(app.MetadataTab);
            app.ChannelCountSpinner.Limits = [0 20];
            app.ChannelCountSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelCountSpinnerValueChanged, true);
            app.MeasurementUnitDropDownLabel = uilabel(app.MetadataTab);
            app.MeasurementUnitDropDownLabel.Text = 'Measurement Unit';
            app.MeasurementUnitDropDown = uidropdown(app.MetadataTab);
            app.MeasurementUnitDropDown.Items = {'Select ...', 'Sound pressure level - dB re 1µPa'};
            app.MeasurementUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @MeasurementUnitDropDownValueChanged, true);
            app.MeasurementUnitDropDown.Value = 'Select ...';
            app.AveragingTimeSpinnerLabel = uilabel(app.MetadataTab);
            app.AveragingTimeSpinnerLabel.Text = 'Averaging Time';
            app.AveragingTimeSpinner = uispinner(app.MetadataTab);
            app.AveragingTimeSpinner.Limits = [0 1200];
            app.AveragingTimeSpinner.ValueChangedFcn = createCallbackFcn(app, @AveragingTimeSpinnerValueChanged, true);
            app.ProcessingAlgorithmDropDownLabel = uilabel(app.MetadataTab);
            app.ProcessingAlgorithmDropDownLabel.Text = 'Processing Algorithm';
            app.ProcessingAlgorithmDropDown = uidropdown(app.MetadataTab);
            app.ProcessingAlgorithmDropDown.Items = {'Select ...', 'AU-BIAS (Tougaard 2019)', 'BIAS (Betke et al 2015)', 'Merchant et al (2015)'};
            app.ProcessingAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @ProcessingAlgorithmDropDownValueChanged, true);
            app.ProcessingAlgorithmDropDown.Value = 'Select ...';
            app.DataUUIDEditFieldLabel = uilabel(app.MetadataTab);
            app.DataUUIDEditFieldLabel.Text = 'Data UUID';
            app.DataUUIDEditField = uieditfield(app.MetadataTab, 'text');
            app.DataUUIDEditField.Editable = 'off';
            app.DatasetVersionSpinnerLabel = uilabel(app.MetadataTab);
            app.DatasetVersionSpinnerLabel.Text = 'Dataset Version';
            app.DatasetVersionSpinner = uispinner(app.MetadataTab);
            app.DatasetVersionSpinner.Limits = [0 Inf];
            app.DatasetVersionSpinner.ValueChangedFcn = createCallbackFcn(app, @DatasetVersionSpinnerValueChanged, true);
            app.CalibrationProcedureDropDownLabel = uilabel(app.MetadataTab);
            app.CalibrationProcedureDropDownLabel.Text = 'Calibration Procedure';
            app.CalibrationProcedureDropDown = uidropdown(app.MetadataTab);
            app.CalibrationProcedureDropDown.Items = {'Select ...', 'Closed pressure chamber/pistonphone (250 Hz)', 'Closed pressure chamber/pistonphone (multiple frequencies)', 'Electric', 'Free field in air', 'Hydrostatic pressure', 'Other', 'Open water calibration', 'Standing wave tank', 'Tank calibration'};
            app.CalibrationProcedureDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibrationProcedureDropDownValueChanged, true);
            app.CalibrationProcedureDropDown.Value = 'Select ...';
            app.Lamp_HydrophoneType = uilamp(app.MetadataTab);
            app.Lamp_HydrophoneSerialNumber = uilamp(app.MetadataTab);
            app.Lamp_RecorderType = uilamp(app.MetadataTab);
            app.Lamp_RecorderSerialNumber = uilamp(app.MetadataTab);
            app.Lamp_MeasurementHeight = uilamp(app.MetadataTab);
            app.Lamp_MeasurementPurpose = uilamp(app.MetadataTab);
            app.Lamp_MeasurementSetup = uilamp(app.MetadataTab);
            app.Lamp_RigDesign = uilamp(app.MetadataTab);
            app.Lamp_FrequencyCount = uilamp(app.MetadataTab);
            app.Lamp_FrequencyIndex = uilamp(app.MetadataTab);
            app.FrequencyIndexPanel = uipanel(app.MetadataTab);
            app.FrequencyIndexPanel.Title = '             Frequency Index';
            app.individualFrequencyBandsCheckBox = uicheckbox(app.FrequencyIndexPanel);
            app.individualFrequencyBandsCheckBox.ValueChangedFcn = createCallbackFcn(app, @individualFrequencyBandsCheckBoxValueChanged, true);
            app.individualFrequencyBandsCheckBox.Text = 'individual Frequency- Bands';
            app.consecutiveThirdOctaveBandsCheckBox = uicheckbox(app.FrequencyIndexPanel);
            app.consecutiveThirdOctaveBandsCheckBox.ValueChangedFcn = createCallbackFcn(app, @consecutiveThirdOctaveBandsCheckBoxValueChanged, true);
            app.consecutiveThirdOctaveBandsCheckBox.Text = 'consecutive Third-Octave Bands';
            app.consecutiveThirdOctaveBandsCheckBox.Value = true;
            app.LowestTOBDropDownLabel = uilabel(app.FrequencyIndexPanel);
            app.LowestTOBDropDownLabel.Text = 'Lowest TOB';
            app.LowestTOBDropDown = uidropdown(app.FrequencyIndexPanel);
            app.LowestTOBDropDown.Items = {'Select ...', '10', '12.5', '16', '20', '25', '31.5', '40', '50', '63', '80', '100', '125', '160', '200', '250', '315', '400', '500', '630', '800', '1000', '1250', '1600', '2000', '2500', '3150', '4000', '5000', '6300', '8000', '10000', '12500', '16000', '20000', '40000', '80000', '160000', ''};
            app.LowestTOBDropDown.ValueChangedFcn = createCallbackFcn(app, @LowestTOBDropDownValueChanged, true);
            app.LowestTOBDropDown.Value = 'Select ...';
            app.HighestTOBDropDownLabel = uilabel(app.FrequencyIndexPanel);
            app.HighestTOBDropDownLabel.Text = 'Highest TOB';
            app.HighestTOBDropDown = uidropdown(app.FrequencyIndexPanel);
            app.HighestTOBDropDown.Items = {'Select ...', '10', '12.5', '16', '20', '25', '31.5', '40', '50', '63', '80', '100', '125', '160', '200', '250', '315', '400', '500', '630', '800', '1000', '1250', '1600', '2000', '2500', '3150', '4000', '5000', '6300', '8000', '10000', '12500', '16000', '20000', '40000', '80000', '160000'};
            app.HighestTOBDropDown.ValueChangedFcn = createCallbackFcn(app, @HighestTOBDropDownValueChanged, true);
            app.HighestTOBDropDown.Value = 'Select ...';
            app.FrequencyIndexEditField = uieditfield(app.FrequencyIndexPanel, 'text');
            app.FrequencyIndexEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyIndexEditFieldValueChanged2, true);
            app.FrequencyIndexEditField.Editable = 'off';
            app.FrequencyIndexEditField.Enable = 'off';
            app.FrequencyIndexEditField.Value = '[63,125,2000]';
            app.LoadFrequencyBandsfromCheckBox = uicheckbox(app.FrequencyIndexPanel);
            app.LoadFrequencyBandsfromCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadFrequencyBandsfromCheckBoxValueChanged, true);
            app.LoadFrequencyBandsfromCheckBox.Text = 'Load Frequency Bands from';
            app.fileButton = uibutton(app.FrequencyIndexPanel, 'push');
            app.fileButton.ButtonPushedFcn = createCallbackFcn(app, @fileButtonPushed, true);
            app.fileButton.Enable = 'off';
            app.fileButton.Text = 'file';
            app.Lamp_FrequencyUnit = uilamp(app.MetadataTab);
            app.Lamp_ChannelCount = uilamp(app.MetadataTab);
            app.MeasurementTotalNoEditFieldLabel = uilabel(app.MetadataTab);
            app.MeasurementTotalNoEditFieldLabel.Text = 'Measurement Total No';
            app.MeasurementTotalNoEditField = uieditfield(app.MetadataTab, 'numeric');
            app.MeasurementTotalNoEditField.RoundFractionalValues = 'on';
            app.MeasurementTotalNoEditField.ValueChangedFcn = createCallbackFcn(app, @MeasurementTotalNoEditFieldValueChanged, true);
            app.Lamp_MeasurementTotalNo = uilamp(app.MetadataTab);
            app.Lamp_MeasurementUnit = uilamp(app.MetadataTab);
            app.Lamp_AveragingTime = uilamp(app.MetadataTab);
            app.Lamp_ProcessingAlgorithm = uilamp(app.MetadataTab);
            app.Lamp_DataUUID = uilamp(app.MetadataTab);
            app.Lamp_DatasetVersion = uilamp(app.MetadataTab);
            app.Lamp_CalibrationProcedure = uilamp(app.MetadataTab);
            app.Lamp_CalibrationDatetime = uilamp(app.MetadataTab);
            app.CalibrationDatetimeDatePickerLabel = uilabel(app.MetadataTab);
            app.CalibrationDatetimeDatePickerLabel.Text = 'Calibration Datetime';
            app.CalibrationDatetimeDatePicker = uidatepicker(app.MetadataTab);
            app.CalibrationDatetimeDatePicker.ValueChangedFcn = createCallbackFcn(app, @CalibrationDatetimeDatePickerValueChanged, true);
            app.TimeEditField_4Label = uilabel(app.MetadataTab);
            app.TimeEditField_4Label.Text = 'Time';
            app.TimeEditField_4 = uieditfield(app.MetadataTab, 'text');
            app.TimeEditField_4.ValueChangedFcn = createCallbackFcn(app, @TimeEditField_4ValueChanged, true);
            app.TimeEditField_4.Value = 'HH:mm';
            app.Lamp_Comments = uilamp(app.MetadataTab);
            app.CommentsTextAreaLabel = uilabel(app.MetadataTab);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsEditField = uitextarea(app.MetadataTab);
            app.CommentsEditField.ValueChangedFcn = createCallbackFcn(app, @CommentsEditFieldValueChanged, true);
            app.Image_9 = uiimage(app.MetadataTab);
            app.Image_9.Tooltip = {'This field describes the manufacturer and the used'; 'hydrophone type/model e.g. ''Brüell&Kjaer 8106''.'; 'This field needs to be an array if there are multiple'; 'channels (one per channel).'; ''; 'https://vocab.ices.dk/?ref=1584'; ''; ''};
            app.Image_9.ImageSource = 'info.png';
            app.Image_10 = uiimage(app.MetadataTab);
            app.Image_10.Tooltip = {'e.g. "SN#1234"This field needs to be an array if there'; 'are multiple channels (one per channel).'};
            app.Image_10.ImageSource = 'info.png';
            app.Image_11 = uiimage(app.MetadataTab);
            app.Image_11.Tooltip = {'Recorder/data logger type e.g. "Soundtrap"'; ''; 'https://vocab.ices.dk/?ref=1585'};
            app.Image_11.ImageSource = 'info.png';
            app.Image_12 = uiimage(app.MetadataTab);
            app.Image_12.Tooltip = {'Recorder serial number e.g. "SN#2345"'};
            app.Image_12.ImageSource = 'info.png';
            app.Image_13 = uiimage(app.MetadataTab);
            app.Image_13.Tooltip = {'Height above the seafloor, in meters'};
            app.Image_13.ImageSource = 'info.png';
            app.Image_14 = uiimage(app.MetadataTab);
            app.Image_14.Tooltip = {'Description of why the continuous underwater noise'; 'measurements reported were monitored'; ''; 'https://vocab.ices.dk/?ref=1586'; ''};
            app.Image_14.ImageSource = 'info.png';
            app.Image_15 = uiimage(app.MetadataTab);
            app.Image_15.Tooltip = {'Description of deployment. Mandatory in case the'; 'purpose is "HELCOM monitoring"'; ''; 'https://vocab.ices.dk/?ref=1587'; ''};
            app.Image_15.ImageSource = 'info.png';
            app.Image_16 = uiimage(app.MetadataTab);
            app.Image_16.Tooltip = {'Description of deployment construction. Mandatory'; 'in case the purpose is "HELCOM monitoring"'; ''; 'https://vocab.ices.dk/?ref=1588'};
            app.Image_16.ImageSource = 'info.png';
            app.Image_17 = uiimage(app.MetadataTab);
            app.Image_17.Tooltip = {'Number of frequency bands'};
            app.Image_17.ImageSource = 'info.png';
            app.Image_18 = uiimage(app.MetadataTab);
            app.Image_18.Tooltip = {'Third octave band nominal center frequencies'};
            app.Image_18.ImageSource = 'info.png';
            app.Image_19 = uiimage(app.MetadataTab);
            app.Image_19.Tooltip = {'https://vocab.ices.dk/?ref=1592'};
            app.Image_19.ImageSource = 'info.png';
            app.Image_20 = uiimage(app.MetadataTab);
            app.Image_20.Tooltip = {'Number of channels used'};
            app.Image_20.ImageSource = 'info.png';
            app.Image_21 = uiimage(app.MetadataTab);
            app.Image_21.Tooltip = {'Number of measurements. This field needs to be an'; 'array if there are multiple channels (one per'; 'channel).'};
            app.Image_21.ImageSource = 'info.png';
            app.Image_22 = uiimage(app.MetadataTab);
            app.Image_22.Tooltip = {'Unit in which the values are in e.g. dB re 1μPa'; ''; 'https://vocab.ices.dk/?ref=1589'; ''};
            app.Image_22.ImageSource = 'info.png';
            app.Image_23 = uiimage(app.MetadataTab);
            app.Image_23.Tooltip = {'Averaging time in seconds'};
            app.Image_23.ImageSource = 'info.png';
            app.Image_24 = uiimage(app.MetadataTab);
            app.Image_24.Tooltip = {'Algorithm used to process the data e.g. computation'; 'method for third octave band (fft, filter bank ...)-'; 'analysis'; ''; 'https://vocab.ices.dk/?ref=1590'; ''};
            app.Image_24.ImageSource = 'info.png';
            app.Image_25 = uiimage(app.MetadataTab);
            app.Image_25.Tooltip = {'Unique identification number, linking the data'; 'submission to the corresponding raw data. It should'; 'be used for resubmissions of the same data; matlab'; 'function available:'; 'uuid = char(java.util.UUID.randomUUID);'};
            app.Image_25.ImageSource = 'info.png';
            app.Image_27 = uiimage(app.MetadataTab);
            app.Image_27.Tooltip = {'Indicates version of the submitted dataset. It should'; 'be changed upon resubmission'};
            app.Image_27.ImageSource = 'info.png';
            app.Image_28 = uiimage(app.MetadataTab);
            app.Image_28.Tooltip = {'Method used to check the'; 'measuring chain. e.g. point calibration with'; 'pistonphone, functionality test with microphone and'; 'loudspeaker (frequency dependent), or other'; 'method used to check the'; 'measuring chain. e.g. point calibration with'; 'pistonphone, functionality test with microphone and'; 'loudspeaker (frequency dependent), or other.'; 'Mandatory in case the purpose is "HELCOM'; 'monitoring"'; ''; 'https://vocab.ices.dk/?ref=1591'; ''};
            app.Image_28.ImageSource = 'info.png';
            app.Image_29 = uiimage(app.MetadataTab);
            app.Image_29.Tooltip = {'Date of when the system was last calibrated.'; 'Mandatory in case "CalibrationProcedure" is'; 'specified UTC DateTime in ISO 8601 format: YYYYMM-'; 'DDThh:mm[:ss] or YYYY-MM-DD hh:mm[:ss]'};
            app.Image_29.ImageSource = 'info.png';
            app.Image_30 = uiimage(app.MetadataTab);
            app.Image_30.Tooltip = {'Add comments to the data submission'};
            app.Image_30.ImageSource = 'info.png';
            app.fromfileButton_2 = uibutton(app.MetadataTab, 'push');
            app.fromfileButton_2.ButtonPushedFcn = createCallbackFcn(app, @fromfileButton_2Pushed, true);
            app.fromfileButton_2.Text = 'from file';
            app.fromfileButton = uibutton(app.MetadataTab, 'push');
            app.fromfileButton.ButtonPushedFcn = createCallbackFcn(app, @fromfileButtonPushed, true);
            app.fromfileButton.Text = 'from file';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.SelectDatetimeVectorButton = uibutton(app.DataTab, 'push');
            app.SelectDatetimeVectorButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDatetimeVectorButtonPushed, true);
            app.SelectDatetimeVectorButton.Text = 'Select Datetime Vector';
            app.DateTimeLabel = uilabel(app.DataTab);
            app.DateTimeLabel.Text = 'DateTime';
            app.Lamp_DateTime = uilamp(app.DataTab);
            app.LeqMeasurementsPanel = uipanel(app.DataTab);
            app.LeqMeasurementsPanel.Title = 'Leq Measurements ';
            app.Image_31 = uiimage(app.DataTab);
            app.Image_31.Tooltip = {'UTC DateTime in ISO 8601 format: YYYY-MM-DDThh:mm[:ss] or YYYY-MMDD'; 'hh:mm[:ss].'};
            app.Image_31.ImageSource = 'info.png';
            app.Image_32 = uiimage(app.DataTab);
            app.Image_32.Tooltip = {'Equivalent continuous sound pressure level measurements over time for'; 'all covered frequency bands. One frequency per column. In case there are'; 'multiple channels, there should be an array of values for each channel. If'; 'there are 3 channels, there would be three arrays called LeqOfChannel1,'; 'LeqOfChannel2, LeqOfChannel3. In case of channel failure, report NAN'; 'values.'};
            app.Image_32.ImageSource = 'info.png';
            app.WritetoICESh5fileButton = uibutton(app.UIFigure, 'push');
            app.WritetoICESh5fileButton.ButtonPushedFcn = createCallbackFcn(app, @WritetoICESh5fileButtonPushed, true);
            app.WritetoICESh5fileButton.Text = 'Write to ICES .h5 file';
            app.MovealldatatoMATLABworkspaceButton = uibutton(app.UIFigure, 'push');
            app.MovealldatatoMATLABworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @MovealldatatoMATLABworkspaceButtonPushed, true);
            app.MovealldatatoMATLABworkspaceButton.Text = 'Move all data to MATLAB workspace';
            app.Viewh5filewithhdfviewButton = uibutton(app.UIFigure, 'push');
            app.Viewh5filewithhdfviewButton.ButtonPushedFcn = createCallbackFcn(app, @Viewh5filewithhdfviewButtonPushed, true);
            app.Viewh5filewithhdfviewButton.Text = 'View .h5 file with hdfview';
            app.GotoICEScontinuousnoiseregistryButton = uibutton(app.UIFigure, 'push');
            app.GotoICEScontinuousnoiseregistryButton.ButtonPushedFcn = createCallbackFcn(app, @GotoICEScontinuousnoiseregistryButtonPushed, true);
            app.GotoICEScontinuousnoiseregistryButton.Text = 'Go to ICES continuous noise registry';
            app.BSH2021Label = uilabel(app.UIFigure);
            app.BSH2021Label.Text = 'BSH, 2021';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = format3000

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end