classdef Udialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScreeningDetailsUIFigure  matlab.ui.Figure
        ExportMenu                matlab.ui.container.Menu
        exportascsvMenu           matlab.ui.container.Menu
        UITable                   matlab.ui.control.Table
    end

    
    properties (Access = private)
        SM % Screening message
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SM)
            T = table({SM.errField}',{SM.errValue}',...
                            {SM.message}');
            app.UITable.Data = T;
            app.SM = SM;
        end

        % Menu selected function: exportascsvMenu
        function exportascsvMenuSelected(app, event)
            %% export table as .csv
            T = table({app.SM.tblFileScreeningID},{app.SM.tblSu_SessionID},{app.SM.line},{app.SM.type},{app.SM.errValue},{app.SM.errField},{app.SM.message});
            [baseFileName, folder] = uiputfile('SessionResults.txt', 'Specify a file');
            fullFileName = fullfile(folder, baseFileName);
            writetable(T,fullFileName,'Delimiter',' ')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScreeningDetailsUIFigure = uifigure('Visible', 'off');
            app.ScreeningDetailsUIFigure.Name = 'Screening Details';
            app.ScreeningDetailsUIFigure.Icon = 'ices.gif';
            app.ExportMenu = uimenu(app.ScreeningDetailsUIFigure);
            app.ExportMenu.Text = 'Export';
            app.exportascsvMenu = uimenu(app.ExportMenu);
            app.exportascsvMenu.MenuSelectedFcn = createCallbackFcn(app, @exportascsvMenuSelected, true);
            app.exportascsvMenu.Text = 'export as .csv';
            app.UITable = uitable(app.ScreeningDetailsUIFigure);
            app.UITable.ColumnName = {'Error Field'; 'Error Value'; 'Error Message'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.ScreeningDetailsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Udialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScreeningDetailsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScreeningDetailsUIFigure)
        end
    end
end